classdef MATLAB_Grader < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                        matlab.ui.Figure
    app_tabs                        matlab.ui.container.TabGroup
    home_tab                        matlab.ui.container.Tab
    home_grid                       matlab.ui.container.GridLayout
    home_submission_buttons         matlab.ui.container.ButtonGroup
    home_sub_original_button        matlab.ui.control.RadioButton
    home_sub_resub_button           matlab.ui.control.RadioButton
    home_courses_table              matlab.ui.control.Table
    home_homeworks_table            matlab.ui.control.Table
    home_roster_table               matlab.ui.control.Table
    home_keyprogs_table             matlab.ui.control.Table
    home_stuprogs_table             matlab.ui.control.Table
    grading_tab                     matlab.ui.container.Tab
    grading_grid                    matlab.ui.container.GridLayout
    grading_program_select_all      matlab.ui.control.Hyperlink
    grading_builtin_detected_table  matlab.ui.control.Table
    grading_student_select_all      matlab.ui.control.Hyperlink
    grading_course_label1           matlab.ui.control.Label
    grading_course_label2           matlab.ui.control.Label
    grading_hw_label1               matlab.ui.control.Label
    grading_hw_label2               matlab.ui.control.Label
    grading_program_table           matlab.ui.control.Table
    grading_student_table           matlab.ui.control.Table
    testcases_tab                   matlab.ui.container.Tab
    tc_refresh_all_tcs              matlab.ui.control.Button
    tc_commands_area                matlab.ui.control.TextArea
    TestCaseHeaderCommandsLabel     matlab.ui.control.Label
    tc_inputs_table                 matlab.ui.control.Table
    tc_label                        matlab.ui.control.Label
    tc_script_area                  matlab.ui.control.TextArea
    tc_cases_table                  matlab.ui.control.Table
    tc_programs_table               matlab.ui.control.Table
    GradingSummaryTab               matlab.ui.container.Tab
    GridLayout4                     matlab.ui.container.GridLayout
    grading_summary_generate_button  matlab.ui.control.Button
    grading_summary_course_label    matlab.ui.control.Label
    grading_summary_table           matlab.ui.control.Table
    settings_tab                    matlab.ui.container.Tab
    GridLayout3                     matlab.ui.container.GridLayout
    settings_std_authorized_builtins  matlab.ui.control.TextArea
    StandardAuthorizedBuiltinFunctionsTextAreaLabel  matlab.ui.control.Label
    TodosTab                        matlab.ui.container.Tab
    BugsFixesandFeatureToDosTextArea  matlab.ui.control.TextArea
    BugsFixesandFeatureToDosTextAreaLabel  matlab.ui.control.Label
    main_app_label                  matlab.ui.control.Label
    home_keyprog_menu               matlab.ui.container.ContextMenu
    home_open_key                   matlab.ui.container.Menu
    home_create_pcode               matlab.ui.container.Menu
    home_set_to_manual_grade        matlab.ui.container.Menu
    home_set_to_auto_grade          matlab.ui.container.Menu
    tc_sets_menu                    matlab.ui.container.ContextMenu
    tc_add_new_set                  matlab.ui.container.Menu
    tc_remove_set                   matlab.ui.container.Menu
    tc_copy_set                     matlab.ui.container.Menu
    grading_student_menu            matlab.ui.container.ContextMenu
    grading_grade_selected_stuprogs  matlab.ui.container.Menu
    grading_open_selected_stuprogs  matlab.ui.container.Menu
    home_course_menu                matlab.ui.container.ContextMenu
    home_add_course                 matlab.ui.container.Menu
    home_del_course                 matlab.ui.container.Menu
    home_import_roster              matlab.ui.container.Menu
    home_homework_menu              matlab.ui.container.ContextMenu
    home_add_hw                     matlab.ui.container.Menu
    home_del_hw                     matlab.ui.container.Menu
    home_import_keys                matlab.ui.container.Menu
    home_stu_program_menu           matlab.ui.container.ContextMenu
    home_import_submissions         matlab.ui.container.Menu
    home_del_program                matlab.ui.container.Menu
    home_rename_program             matlab.ui.container.Menu
    home_open_stu                   matlab.ui.container.Menu
    grading_builtin_menu            matlab.ui.container.ContextMenu
    grading_add_builtin_to_std_list  matlab.ui.container.Menu
  end

  properties (Access = private)

    dirs % Description

    settings                            % Imported settings info
    base_testcases % Description
    course_name % Name of currently working course
    course_settings
    course
    course_roster                              % Table imported from roster.mat
    home
    hw_settings                           % Current hw appreviation value
    program_name % Description
    program % Description
    program_settings % Description
    student_name                     % Name of the currently selected student (user can change this)
    results               % Table imported from results.mat
    results_path
    Grading_Dir         % Path to working grading folder
    Submission_Dir           % Path to student programs (subfolder of Current_Grading_Folder)
    ReSubmission_Dir           % Path to student resubmission programs (subfolder of Current_Grading_Folder)
    Results_Dir              % Path to current results folder (user can change this)
    ReResults_Dir              % Path to current resub results folder (user can change this)
    Inputs_Dir               % Path to inputs for each program (subfolder of Current_Grading_Folder)
    Key_Dir                  % Path to instructor programs (subfolder of Current_Submission_Folder)
    Inputs                              % Table imported from inputs.mat
    Program                     % Name of the currently selected program (user can change this)
    Student_Result_File         % Path + Filename for student_results.mat
    Student_Result_Table        % Table imported from student_results.mat for a particular student
    Selected_Note               % Currently selected note
    Selected_Note_Row           % Row number of currently selected note
    Selected_Student_Note_Row   % Row number of currently selected student note
    Selected_Key_Programs       % Rows of the currently selected key programs
    Authorized_Functions                % List of Functions that students are permitted to use
    %         Icon_Path = addpath('./data/App Icons')    % Adds a path to the icon files

    submission_type % Description
    courses % Description
    students % Description
    keyprograms % Description
    stuprograms % Description
    testcases % Description
    homeworks % Description
    builtins % Description
    cur_course % Description
    cur_students % Students table filtered by course
    imported_keys % Description
    selected_students % Student table filtered by student
    selected_key_progs % Description
    selected_stu_progs % Description
    cur_homeworks % Description
    cur_keyprograms % Programs table filtered by course
    cur_stuprograms % Description
    cur_testcases % Description
    submission_file % Description
    tc_keyprog_row % Description
    tc_set % Description
    cur_tc_hw % Description
    cur_tc_prog % Description
    cur_tc_set % Description
    styles % Description
    grading_info % Description
    hw_key % Description
    hw_key_name % Description
    hw_key_file % Description
    hw_stu % Description
    tc_text_area % Description
    initializing % Description
    grading % Description
    tc_keyprog % Description
  end

  properties (Access = public)
    unresponsive = false  % set to true if while loop appears to be stuck
  end

  % initialization methods =============================================
  methods (Access = private)

    %-----------------------------------------------------------------
    function initialize_styles(app)

      % Add button icon style
      new_icon = [app.dirs.icons '\plus_02.png'];
      app.styles.add = ...
        uistyle("Icon",new_icon,"IconAlignment","rightmargin");

      % Remove button icon style
      new_icon = [app.dirs.icons '\minus_01.png'];
      app.styles.remove = ...
        uistyle("Icon",new_icon,"IconAlignment","center");

      % Copy button icon style
      new_icon = [app.dirs.icons '\copy_01.png'];
      app.styles.copy = ...
        uistyle("Icon",new_icon,"IconAlignment","rightmargin");

      % Horizontal alignment style
      app.styles.hor_align = uistyle("HorizontalAlignment",'center');

      % Selection background styles
      app.styles.bg_blue = uistyle('BackgroundColor','#B5CBF8');

    end
    %-----------------------------------------------------------------
    function initialize_app_data(app)

      % import the course data if it exists
      import_data_type(app, 'courses')

      % import the student data if it exists
      import_data_type(app, 'students')

      % import the program data if it exists
      import_data_type(app, 'homeworks')

      % import the key programs data if it exists
      import_data_type(app, 'keyprograms')

      % import the student programs data if it exists
      import_data_type(app, 'stuprograms')

      % import the test case data if it exists
      import_data_type(app, 'testcases')

      % import the results data if it exists
      import_data_type(app, 'results')

      % import the builtin function data if it exists
      import_data_type(app, 'builtins')

    end


    function preprocess_program(~, temp_path, new_path)
      
      program_lines = readlines(temp_path);

      function_found = false;
      while_found = false;

      last_row = height(program_lines);
      row = 1;

      % scan through each line looking for function and while text
      while row < last_row

        line = program_lines(row);

        if isempty(line)
          continue
        end

        % if function is found and it is not contained in a comment, get
        % the row it is in
        if contains(line, "function ") && ~function_found

          fun_idx = strfind(line, 'function ');
          com_idx = strfind(line, '%');

          if isempty(com_idx) || fun_idx(1) < com_idx

            fun_row = row;
            function_found = true;

          end

        end

        % if while is found and add in some code to catch infinite loops
        if contains(line, "while ")

          while_idx = strfind(line, 'while ');

          com_idx = strfind(line, '%');

          if isempty(com_idx) || while_idx(1) < com_idx

            while_tab = line{1}(1:while_idx-1);

            before_while = program_lines(1:row - 1);
            after_while = program_lines(row + 1:end);

            new_while = [ ...
              while_tab + "inf_loop_counter = 0;"; ...
              line; ...
              while_tab + "    inf_loop_counter = inf_loop_counter + 1;"; ...
              while_tab + "    if inf_loop_counter > 1e6"; ...
              while_tab + "        unresponsive = true;"; ...
              while_tab + "        return"; ...
              while_tab + "    end"; ...
              ];

            program_lines = [before_while ; new_while ; after_while];

            while_found = true;
            row = row + 5;

          end

        end
  
        row = row + 1;
        last_row = height(program_lines);

      end

      % if at least one while was found add the unresponsive global
      % variable just below the function header
      if while_found

        program_lines = [ ...
          program_lines(1:fun_row); ...
          "global unresponsive"; ...
          program_lines(fun_row + 1:end);
          ];
      end

      writelines(program_lines, new_path);


%       % First line of orginal program
%       text_line = fgetl(f_Input);
% 
%       % scan through each line of orginal program
%       while ischar(text_line)
% 
%         % look for a function string
%         fun_idx = strfind(text_line,'function ');
% 
%         % look for a while string
%         while_idx = strfind(text_line,'while ');
%         while_tab = text_line(1:while_idx-1);
% 
%         % if "function" is found make sure it is not in a comment
%         if ~isempty(fun_idx)
% 
%           comment_idx = strfind(text_line,'%');
% 
%           if comment_idx > fun_idx
% 
%             is_function = true;
% 
%           end
% 
%         end
% 
%         if ~function_found && is_function
%           fprintf( f_Output, '%s\n', text_line );
%           fprintf( f_Output, 'global unresponsive\n');
%           function_found = true;
%         elseif ~isempty(while_idx)
%           if isempty(comment_idx) || while_idx(1) < comment_idx(1)
%             fprintf( f_Output, [while_tab,'inf_loop_counter = 0;\n']);
%             fprintf( f_Output, '%s\n', text_line );
%             fprintf( f_Output, [while_tab,'    inf_loop_counter = inf_loop_counter + 1; \n']);
%             fprintf( f_Output, [while_tab,'    if inf_loop_counter > 1e6\n'] );
%             fprintf( f_Output, [while_tab,'        unresponsive = true;\n'] );
%             fprintf( f_Output, [while_tab,'        return\n'] );
%             fprintf( f_Output, [while_tab,'    end\n'] );
%           end
% 
%         else
%           fprintf( f_Output, '%s\n', text_line );
%         end
%         text_line = fgetl(f_Input);
%       end
%       fclose( f_Input );
%       fclose( f_Output );
% 
%     end

    end

  end

  % load methods =======================================================
  methods (Access = private)

    %-----------------------------------------------------------------
    function load_course(app)

      % Is the selected course is missing?
      if ismissing(app.home.course)

        % Set the 1st course as the selected course
        app.home.course = app.courses.name(1);

      end

      % Filter the course data by course
      app.cur_course = app.courses( ...
        app.courses.name == app.home.course, :);

      % Filter the homework data by course
      app.cur_homeworks = app.homeworks( ...
        app.homeworks.course == app.home.course, :);

      % Is the selected hw missing?
      if ismissing(app.home.hw) && height(app.cur_homeworks) > 0

        % Set the 1st hw in this course as the selected hw
        app.home.hw = app.cur_homeworks.name(1);

      end

      % Filter the student data by course
      app.cur_students = app.students( ...
        app.students.course == app.home.course, :);

      % Is there at least one student in this course?
      if ismissing(app.home.student) && height(app.cur_students) > 0

        % Set the selected key student
        app.home.student = app.cur_students.last_name(1);

      end

      % Filter the keyprogram data by course and homework
      app.cur_keyprograms = app.keyprograms( ...
        app.keyprograms.course == app.home.course & ...
        app.keyprograms.hw == app.home.hw, :);

      % Is there at least one key program for this hw?
      if ismissing(app.home.keyprog) && ...
          height(app.cur_keyprograms) > 0

        % Set the selected key program
        app.home.keyprog = app.cur_keyprograms.program(1);

      end

      % Filter the stuprogram data by course, homework, student, &
      % submission type
      app.cur_stuprograms = app.stuprograms( ...
        app.stuprograms.course == app.home.course & ...
        app.stuprograms.hw == app.home.hw & ...
        app.stuprograms.last_name == app.home.student & ...
        app.stuprograms.resubmission == app.home.resub, :);

      % Is there at least one student program?
      if ( ...
          all(app.home.stuprog == "") || ...
          isempty(app.home.stuprog) ...
          ) ...
          && height(app.cur_stuprograms) > 0

        % Set the selected student program
        app.home.stuprog = app.cur_stuprograms.submission_name{1};
        app.home.stuprog_id = app.cur_stuprograms.stuprogram_id(1);

      end

      % Set path directories
      app.dirs.course = [app.dirs.root '\' char(app.home.course)];
      app.dirs.hw = [app.dirs.course '\' char(app.home.hw)];
      app.dirs.students = [app.dirs.course '\students'];
      app.dirs.student = ...
        [app.dirs.students '\' char(app.home.student)];
      app.dirs.stuprogram = [app.dirs.student '\' char(app.home.hw)];

      % Set last course and homework worked on
      app.settings.last_course = app.home.course;
      app.settings.last_hw = app.home.hw;
      data_save_mat_file(app, 'settings')

      %             % Load the homework and student data
      %             load_homeworks(app);
      %             load_students(app);

    end
    %-----------------------------------------------------------------
    function load_students(app)

      % Set the current students data
      course_filter = app.students.course == app.home.course;
      app.cur_students = app.students(course_filter,:);

      % Set students directory
      app.dirs.students = [app.dirs.course '\students'];

      % Are there no students in this course?
      if height(app.cur_homeworks) == 0

        return

      end

      % Are there any students to display?
      if height(app.cur_students) > 0

        % Set current student as first student
        app.student_name = char(app.cur_students.last_name(1));

        load_student(app)

      end

      %             display_interface(app, 'students', 'new')

    end
    %-----------------------------------------------------------------
    function load_homeworks(app)

      % Set the current homeworks data
      course_filter = app.homeworks.course == app.home.course;

      app.cur_homeworks = app.homeworks(course_filter,:);

      if app.home.hw.row > 0

        app.home.hw = app.cur_homeworks.name(app.home.hw.row);

      elseif height(app.cur_homeworks) > 0

        app.home.hw = app.cur_homeworks.name(1);

      end

      % Set hw directory
      app.dirs.hw = [app.dirs.course '\' char(app.home.hw)];

      % Load the assigned programs data
      load_key_programs(app);

    end
    %-----------------------------------------------------------------
    function load_student(app)

      % Set students directory
      app.dirs.student = ...
        [app.dirs.students '\' char(app.home.student)];

      % Load current results data
      if ~isempty(app.results) && ...
          ~isnan(app.results.result_id(1))
        load_results(app)
      end

      % Load current student program data
      if ~isempty(app.stuprograms) && ...
          ~isnan(app.stuprograms.stuprogram_id(1))
        load_stu_programs(app)
      end

      display_interface(app, 'student', 'new')

    end
    %-----------------------------------------------------------------
    function load_key_programs(app)

      % Filter the key programs table by course and hw
      course_filter = app.keyprograms.course == app.home.course;
      hw_filter = app.keyprograms.hw == app.home.hw;

      app.cur_keyprograms = app.keyprograms( ...
        course_filter & ...
        hw_filter ...
        ,:);

    end
    %-----------------------------------------------------------------
    function load_stu_programs(app)

      if height(app.stuprograms) > 0

        % Filter the student programs table by student, hw, course
        course_filter = app.stuprograms.course == app.home.course;
        hw_filter = app.stuprograms.hw == app.home.hw;
        stu_filter = app.stuprograms.last_name == app.student_name;
        resub_filter = ...
          app.stuprograms.resubmission == app.home.resub;
        ext_filter = app.stuprograms.file_ext == 'm';

        app.cur_stuprograms = ...
          app.stuprograms( ...
          course_filter & ...
          hw_filter & ...
          stu_filter & ...
          ext_filter & ...
          resub_filter, ...
          :);

        % Set student program directory
        app.dirs.stuprogram = ...
          [app.dirs.student '\' char(app.home.hw)];

      else

        % No students to load yet
        app.cur_stuprograms = app.stuprograms;

      end
      %
      %             display_interface(app, 'stuprograms', 'new')

    end
    %-----------------------------------------------------------------
    function load_results(app)

      % Filter the student programs table by student, hw and course
      course_filter = app.stuprograms.course == app.home.course;
      hw_filter = app.stuprograms.hw == app.home.hw;

      app.cur_results = ...
        app.results(course_filter & hw_filter,:);

      display_interface(app, 'results', 'new')

    end
    %-----------------------------------------------------------------
    function load_testcases(~)

      % Update the testcase set table

    end
    %-----------------------------------------------------------------
    function load_tc_sets(~)


    end
    %-----------------------------------------------------------------
    function text_area = load_tc_output_file(app, func)

      % Pre-allocate and set the first line of the tc text area
      text_area(1:100,1) = "";
      text_area(1) = "Test Cases for " + string(app.tc_keyprog);

      % Import and parse it
      tc_func_lines = readlines(func);

      line_ct = 1;

      % Flag indicating the start of the tc lines
      found_lines = false;

      for n = 1:length(tc_func_lines)

        line = tc_func_lines(n);

        % Were the tc lines of the file found yet?
        if ~found_lines

          % Is this the start of the tc lines?
          if contains(line, '    % Generate Outputs')

            found_lines = true;

          end

          continue

        end

        % Are we at the end of the tc lines?
        if contains(line, 'end')

          % Delete all trailing lines and terminate loop
          text_area(line_ct:end) = [];
          break

        end

        % Set the string we are looking for
        look_for = "outputs(" + num2str(line_ct) + ",:)";

        % Does the line contain the string we are looking for
        if contains(line, look_for)

          % Remove all but the input values
          line = erase(line, look_for + " = func_handle( ");
          line = erase(line, " );");

        end

        line_ct = line_ct + 1;

        % Add this cleaned line to the text area without
        % whitespace
        text_area(line_ct) = strip(line);

      end

    end
    %-----------------------------------------------------------------


  end

  % create methods =====================================================
  methods (Access = private)

    function create_new_course(app)

      % First, get a name for the course from the user
      while true
        user_input = inputdlg({'Enter the New Course Name:'}, ...
          'Create New Course Folder', ...
          [1 35], ...
          {'MA'});

        new_course_name = user_input{1};
        app.dirs.course = [app.dirs.root '\' new_course_name];

        if exist(app.dirs.course,'file')
          answer = questdlg( ...
            "This course already exists. Are you " + ...
            "sure you want to delete the current " + ...
            new_course_name + " folder and start " + ...
            "fresh?", ...
            'Course Already Exists','Yes','No','No');
          if isequal(answer,'Yes')
            break
          end
        else
          break
        end

      end

      if isempty(new_course_name)
        return
      end

      % Construct new course row with defaults
      new_course_row = table();
      new_course_row.course_id = height(app.courses) + 1;
      new_course_row.name = categorical({new_course_name});
      new_course_row.date_added = date;

      % Check if this is the first course in the database
      if ~isempty(app.courses) && isnan(app.courses.course_id(1))

        app.courses(1,:) = [];

      end

      % Is this the first course being added to the database?
      if height(app.courses) == 0

        % Just set the table to the new rows
        app.courses = new_course_row;

      else

        % Concatenate the new rows
        app.courses = [app.courses; new_course_row];

      end

      % Save the changes to the csv file
      data_save_datatable(app, 'courses')

      % Set the course directory location
      app.dirs.course = [app.dirs.root '\' new_course_name];

      % Reset home tab selection info
      clear_home_tab_info(app)

      % Set this course as the selected course
      app.home.course = categorical({new_course_name});

      % Create a course directory
      create_directory_structure(app, 'courses')

      %             % Create a settings mat file
      %             app.course_settings.std_builtins = categorical("");
      %             data_save_mat_file(app, 'course_settings')

    end
    %-----------------------------------------------------------------
    function create_new_roster(app)

      %             % Message to user
      %             uiwait(msgbox([ 'Navigate to the csv file containing ' ...
      %                 'your course roster. This file should have column ' ...
      %                 'headers with a lastname and firstname column.'] ...
      %                 ,'Key Programs','modal'));

      % Get filenames from user
      [file, path] = uigetfile( {'*.csv'}, ...
        'Select One CSV File', 'MultiSelect', 'off');

      %             file = "MA-310-Roster-FL22.csv";
      % %             path = "G:\My Drive\Teaching\2022_F\program_grader";
      %             path = 'C:\Users\gcox\Google Drive\Teaching\2022_F\program_grader';

      try
        % Import users roster
        roster_csv = fullfile(path, file);
        user_table = readtable(roster_csv);

        % get the column headers
        header = user_table.Properties.VariableNames;

        % scan throught the header to find the lastnames
        for k = 1:length(header)

          % find the "lastnames"
          if isequal(lower(header{k}),'lastname') || ...
              isequal(lower(header{k}),'last name') || ...
              isequal(lower(header{k}),'last_name')

            last_names = string(user_table.(header{k}));
            break
          end

        end

        % scan through the header to find the firstnames
        for k = 1:length(header)

          % find the "firstnames"
          if isequal(lower(header{k}),'firstname') || ...
              isequal(lower(header{k}),'first name') || ...
              isequal(lower(header{k}),'first_name')

            first_names = string(user_table.(header{k}));
            break
          end

        end

        % create new student folders in the course directory
        for k = 1:length(last_names)

          mkdir([app.dirs.course '\students\'], last_names{k});

        end

      catch

        uiwait(msgbox([ 'ERROR : There was a problem ' ...
          'importing your roster. Please make sure it ' ...
          'is a csv file that has a "LastName" and ' ...
          '"FirstName" column header.' ...
          ] ...
          ,'ROSTER','modal'));

        return

      end

      if isempty(app.students) || isnan(app.students.student_id(1))

        next_id = 1;

      else

        next_id = app.students.student_id(end) + 1;

      end

      num_added = length(last_names);

      % Create a categorical array for the course column
      course_col = repmat(app.home.course, num_added, 1);
      course_col = categorical(cellstr(course_col));

      % Construct new roster with defaults
      new_roster = table();
      new_roster.student_id = (next_id : next_id + num_added - 1)';
      new_roster.last_name = categorical(last_names);
      new_roster.first_name = categorical(first_names);
      new_roster.course = course_col;
      new_roster.full_name = string(new_roster.first_name) + ...
        " " + string(new_roster.last_name);
      new_roster.date_added = repmat(date, num_added, 1);

      % Add roster to the student table
      app.students = [app.students; new_roster];

      % Save the changes to the csv file
      data_save_datatable(app, 'students')

      % Filter the student table by course
      app.cur_students = ...
        app.students(app.students.course == app.home.course,:);

      % Set the student directory location
      app.dirs.students = [app.dirs.course '\students'];

    end
    %-----------------------------------------------------------------
    function create_new_hw_set(app)

      % Check to see if a course is selected
      if isempty(app.home.course)

        msgbox(['Please select a course from the table ' ...
          'before trying to add a new homework set.'])

        return

      end

      % Are there no students in this course yet?
      if height(app.cur_students) == 0

        msgbox(['Please import a course roster before adding ' ...
          'a new homework set.'])

        return

      end

      % Create the name for the new homework folder
      num_of_hws = height(app.cur_homeworks);

      if num_of_hws < 10

        new_hw_name = ['HW_0',num2str(num_of_hws + 1)];

      else

        new_hw_name = ['HW_',num2str(num_of_hws + 1)];

      end

      % Construct new hw row with defaults
      new_hw_row = table();
      new_hw_row.homework_id = height(app.homeworks) + 1;
      new_hw_row.name = new_hw_name;
      new_hw_row.pt_value = 0;
      new_hw_row.date_added = date;
      new_hw_row.course = app.home.course;

      % Add this homework to the database
      app.homeworks = [app.homeworks; new_hw_row];

      % Save the changes to the csv file
      data_save_datatable(app, 'homeworks')

      % Set this course as the selected course
      app.home.hw = categorical({new_hw_name});

      % Create a homework directory
      app.dirs.hw = [app.dirs.course '\' new_hw_name];
      create_directory_structure(app, 'homeworks')

      % Add results data for this homework
      msgbox('results data is in development. Skipping for now.')
      %create_new_hw_results(app)

    end

    %-----------------------------------------------------------------
    function create_new_hw_results(app)

      n_rows = height(app.roster);

      % Pre-allocate cell arrays
      score = zeros(n_rows,1);
      comments = string(n_rows,1);
      deductions = string(n_rows,1);

      if height(app.results) == 0
        next_id = 1;
      else
        next_id = app.results.result_id(end) + 1;
      end

      % Create a categorical array for the course column
      course_col = repmat(app.home.course, n_rows, 1);
      course_col = categorical(cellstr(course_col));

      % Create a categorical array for the hw column
      hw_col = repmat(app.home.hw, n_rows, 1);
      hw_col = categorical(cellstr(hw_col));

      % Create a categorical array for the sub type column
      sub_type_col = repmat(app.home.submission_type, n_rows, 1);
      sub_type_col = categorical(cellstr(sub_type_col));

      % Create a categorical array for the effort column
      effort_col = repmat("good", n_rows, 1);
      effort_col = categorical(cellstr(effort_col));

      % Construct new key program set with defaults
      new_results = table();
      new_results.result_id = ...
        (next_id : next_id + num_of_files - 1)';
      new_results.last_name = roster.last_name;
      new_results.first_name = roster.first_name;
      new_results.score = score;
      new_results.comments = comments;
      new_results.deductions = deductions;
      new_results.submission_type = sub_type_col;
      new_results.manual_grade = manual_grade;
      new_results.effort = effort_col;
      new_results.date_added = repmat(date, num_of_files, 1);
      new_results.course = course_col;
      new_results.hw = hw_col;

      % Is this the first keyprogram being added to the database?
      if height(app.results) == 0

        % Just set the table to the new rows
        app.results = new_results;

      else

        % Concatenate the new rows
        app.results = [app.results; new_results];

      end

      % Save the changes to the csv file
      data_save_datatable(app, 'results')

    end

    %-----------------------------------------------------------------
    function create_new_key_programs(app)

      % Initialize Progress Bar
      progressbar = uiprogressdlg( app.UIFigure, ...
        'Title', 'Progress', ...
        'Message', 'Importing New Key Programs', ...
        'Value', 0, ...
        'ShowPercentage', 'on');

      m_names = app.imported_keys.files';
      fpath = app.imported_keys.path;

      num_of_files = length(m_names);

      % Pre-allocate cell arrays
      prefix = cell(num_of_files,1);
      name = cell(num_of_files,1);
      pt_value = 10*ones(num_of_files,1);
      n_inputs = zeros(num_of_files,1);
      n_outputs = zeros(num_of_files,1);
      n_sets = zeros(num_of_files,1);
      manual_grade = true(num_of_files,1);

      % Scan through each selected file
      for k = 1:num_of_files

        % Update the progress bar
        progressbar.Value = k/num_of_files;

        % get the program names and identifiers
        if contains(m_names{k},'_')
          split_key_name = split(m_names{k}, '_');
          prefix{k} = split_key_name{1};
        else
          prefix{k} = '';
        end

        name{k} = erase(m_names{k},'.m');

        % Get the input and output variables
        var_names = get_variable_names(app, name{k}, fpath);
        n_inputs(k) = length(var_names.input);
        n_outputs(k) = length(var_names.output);

        % Set directory and fullpath to file
        key_dir = [app.dirs.hw '\' name{k}];

        % create new program directory
        mkdir(key_dir)

        % copy the selected key program to the key directory
        copyfile([fpath m_names{k}], [key_dir '\' m_names{k}])

        % Create empty testcase data for this program
        create_new_program_test_cases(app, name{k}, key_dir)

      end

      if height(app.keyprograms) == 0
        next_id = 1;
      else
        next_id = app.keyprograms.keyprogram_id(end) + 1;
      end

      % Create a categorical array for the course column
      course_col = repmat(app.home.course, num_of_files, 1);
      course_col = categorical(cellstr(course_col));

      % Create a categorical array for the hw column
      hw_col = repmat(app.home.hw, num_of_files, 1);
      hw_col = categorical(cellstr(hw_col));

      % Construct new key program set with defaults
      new_key_set = table();
      new_key_set.keyprogram_id = ...
        (next_id : next_id + num_of_files - 1)';
      new_key_set.program = categorical(name);
      new_key_set.prefix = categorical(prefix);
      new_key_set.m_name = string(m_names);
      new_key_set.pt_value = pt_value;
      new_key_set.n_inputs = n_inputs;
      new_key_set.n_outputs = n_outputs;
      new_key_set.n_sets = n_sets;
      new_key_set.manual_grade = manual_grade;
      new_key_set.date_added = repmat(date, num_of_files, 1);
      new_key_set.course = course_col;
      new_key_set.hw = hw_col;

      % Is this the first keyprogram being added to the database?
      if height(app.keyprograms) == 0

        % Just set the table to the new rows
        app.keyprograms = new_key_set;

      else

        % Concatenate the new rows
        app.keyprograms = [app.keyprograms; new_key_set];

      end

      % Filter the programs table by course and hw
      course_filter = app.keyprograms.course == app.home.course;
      hw_filter = app.keyprograms.hw == app.home.hw;

      app.cur_keyprograms = ...
        app.keyprograms(course_filter & hw_filter,:);

      % Save the changes to the csv file
      data_save_datatable(app, 'keyprograms')

      % Add a key output file for testcases
      create_new_key_output(app)

      close(progressbar);

    end
    %-----------------------------------------------------------------
    function create_new_stu_programs(app)

      % Initialize Progress Bar
      progressbar = uiprogressdlg( app.UIFigure, ...
        'Title', 'Please wait...', ...
        'Message', 'Importing Student Programs', ...
        'Value', 0, ...
        'ShowPercentage', 'on');

      if isempty(app.submission_file)
        return
      end

      % Determine the submission type
      if app.home.resub
        app.home.subtype = 'resubmission';
      else
        app.home.subtype = 'original';
      end

      % Delete any previous imports for this particular hw
      del_filter = app.stuprograms.course == app.home.course & ...
        app.stuprograms.hw == app.home.hw;

      app.stuprograms(del_filter,:) = [];

      % create a temporary folder to dump all the files in
      temp_folder = [app.dirs.hw,'\temp_submission'];

      % dump all files from the submission file/folder into this
      % temporary folder
      dump_all_files_to_folder(app, app.submission_file, temp_folder)

      % Get the directory listing
      unzip_dir_list = dir(temp_folder);

      % Get all the programs in this directory
      all_programs = {unzip_dir_list.name}';
      all_programs(1:2) = [];

      % Get lastnames and firstnames from roster
      lastname_list = app.cur_students.last_name;
      firstname_list = app.cur_students.first_name;

      n_students = length(lastname_list);

      builtin_collection = strings(1000,1);
      L_idx = 1;

      % Preallocate new rows table
      if height(app.stuprograms) > 0

        new_rows(1001,:) = app.stuprograms(end,:);
        new_rows(1001,:) = [];

      else

        % set new_rows to an empty student programs table
        new_rows = app.stuprograms;
        % hard-code some generic row with the same data types
        new_rows(1001,:) = ...
          {"","","","","","","",0,0,0,"","","","","",""};
        new_rows.builtins(1001,1:10) = strings(1,10);
        new_rows(1001,:) = [];

      end

      % set the next key index value
      if height(app.stuprograms) == 0

        next_id = 0;

      else

        next_id = app.stuprograms.stuprogram_id(end);

      end

      i0 = 1;

      addpath(temp_folder)

      for k = 1:n_students

        % current student and their submission path
        lastname = char(lastname_list(k));
        firstname = char(firstname_list(k));

        % Update the progress bar
        progressbar.Value = k/n_students;
        progressbar.Message = ...
          ['Importing ' lastname '''s Programs'];

        % match all files from directory with this lastname
        matching_files = contains(all_programs, ...
          lastname, 'IgnoreCase', true);

        % if no matches skip to the next student
        if ~any(matching_files)
          continue
        end

        % collect all of matching student's programs
        student_temp_files = all_programs(matching_files);

        % set hw name and submission type folder
        type_dir = [char(app.home.hw) '\' app.home.subtype];

        % get the current student's hw directory
        submit_dir = ...
          [app.dirs.students '\' lastname '\' type_dir];

        % count the number of student files
        n_stu_files = length(student_temp_files);

        % Pre-allocate column arrays
        submission_name = cell(n_stu_files, 1);
        expected_name = cell(n_stu_files, 1);
        name_in_file = cell(n_stu_files, 1);
        file_ext = cell(n_stu_files, 1);
        file_type = cell(n_stu_files, 1);
        matched_keys = strings(n_stu_files, 1);
        mis_titled = false(n_stu_files, 1);
        late = false(n_stu_files, 1);
        builtins_detected(1:n_stu_files,1:10) = "";

        [f_types, f_ids] = isfunction(app, student_temp_files);

        % Scan through and clean these programs before copying
        % them into the student's submission folder
        for j = 1:n_stu_files

          raw_file = student_temp_files{j};

          % Get file name and path of jth program
          temp_path = [temp_folder '\' raw_file];
          file_split = split(raw_file,'.');
          file_ext{j} = lower(file_split{end});

          sub_name = ...
            erase(student_temp_files{j}, ['.' file_ext{j}]);

          % Get the name used in their function
          split_name = split(sub_name, '_');
          name_text = split_name{end};
          split_name_text = split(name_text, '-');
          name_found = split_name_text{1};

          name_in_file{j} = name_found;

          % Get the file type
          if f_types(j)
            file_type{j} = 'function';

            % Get the filename used in their function header
            sub_name = get_function_name(app, temp_path);

          elseif f_ids(j) == -1
            file_type{j} = 'script';
          else
            file_type{j} = 'unknown';
          end

          switch file_ext{j}

            case 'm'

              new_path = [...
                submit_dir '\' sub_name '.' file_ext{j} ...
                ];

            otherwise

              new_path = [...
                submit_dir '\non_m_files\' ...
                sub_name '.' file_ext{j} ...
                ];

          end

          preprocess_program(app,temp_path, new_path)

          %                     copyfile(temp_path, new_path);

          expected_name{j} = erase(sub_name, ['_' name_found]);

          submission_name{j} = sub_name;

          % See if the program was submitted late
          late(j) = ...
            contains(lower(student_temp_files{j}),'_late_');

          % Generate a list of functions detected
          funcs_detected = functions_in_function(app, new_path);
          n_detected = length(funcs_detected.builtin);
          builtins_detected(j,1:min(n_detected,10)) = ...
            funcs_detected.builtin(1:min(n_detected,10));

        end

        % Attempt to match each submitted program with a key
        key_list = app.cur_keyprograms.program;
        dist = inf(1,length(key_list));
        idx = zeros(1,length(key_list));
        c = 1;
        while ~isempty(key_list)

          % Get and convert the first key to a char
          key = char(key_list(1));

          % Determine the index of the submission name that is
          % closest to the key
          [dist(c), idx(c)] = ...
            find_closest_file_match(app, expected_name, key);

          %                     % Set this key as the closest match
          %                     matched_keys(idx(c)) = key;
          %
          %                     % Determine of the program was mistitled
          %                     if dist(c) > 0
          %
          %                         mis_titled(idx(c)) = true;
          %
          %                     end

          % Delete this key from the key list and repeat
          key_list(1) = [];

          c = c + 1;

        end

        key_list = app.cur_keyprograms.program;
        for j = 1:n_stu_files

          % Get the closest match
          [~, min_idx] = min(dist);

          % Set this key as the closest match
          matched_keys(idx(min_idx)) = key_list(min_idx);

          % Determine of the program was mistitled
          if dist(min_idx) > 0

            mis_titled(idx(min_idx)) = true;

          end

          dist(min_idx) = [];
          idx(min_idx) = [];
          key_list(min_idx) = [];

        end

        % Create a categorical array for the course column
        course_col = repmat(app.home.course, n_stu_files, 1);
        course_col = categorical(cellstr(course_col));

        % Create a categorical array for the hw column
        hw_col = repmat(app.home.hw, n_stu_files, 1);
        hw_col = categorical(cellstr(hw_col));

        % Create a categorical array for the last name column
        last_name_col = repmat(lastname, n_stu_files, 1);
        last_name_col = categorical(cellstr(last_name_col));

        % Create a categorical array for the first name column
        first_name_col = repmat(firstname, n_stu_files, 1);
        first_name_col = categorical(cellstr(first_name_col));

        % Create a logical array for the resubmission column
        if strcmp(app.home.subtype, 'original')
          resubmission = false(n_stu_files, 1);
        else
          resubmission = true(n_stu_files, 1);
        end

        % Construct new key program set with defaults
        new_stu_set = table();
        new_stu_set.stuprogram_id = ...
          (i0 + next_id : next_id + i0 + n_stu_files - 1)';
        new_stu_set.submission_name = string(submission_name);
        new_stu_set.expected_name = string(expected_name);
        new_stu_set.name_in_file = string(name_in_file);
        new_stu_set.file_ext = categorical(file_ext);
        new_stu_set.file_type = categorical(file_type);
        new_stu_set.matched_key_name = categorical(matched_keys);
        new_stu_set.mis_titled = mis_titled;
        new_stu_set.resubmission = resubmission;
        new_stu_set.late = late;
        new_stu_set.date_added = repmat(date, n_stu_files, 1);
        new_stu_set.course = course_col;
        new_stu_set.hw = hw_col;
        new_stu_set.last_name = last_name_col;
        new_stu_set.first_name = first_name_col;
        new_stu_set.builtins = builtins_detected;

        % Add the new builtins to the collection
        new_builtins = builtins_detected(:);
        new_builtins(new_builtins == "") = [];
        R_idx = L_idx + length(new_builtins) - 1;
        builtin_collection(L_idx:R_idx) = new_builtins;
        L_idx = R_idx + 1;

        % delete the builtins_detected variable
        clear builtins_detected

        new_rows(i0:i0 + n_stu_files - 1, :) = new_stu_set;

        i0 = i0 + n_stu_files;

      end

      rmpath(temp_folder)

      new_rows(i0:end, :) = [];

      % Is this the first testcase being added to the database?
      if height(app.stuprograms) == 0

        % Just set the table to the new rows
        app.stuprograms = new_rows;

      else

        idx_filter = false(height(app.stuprograms), 1);
        idx_replace = false(height(new_rows), 1);

        for k = 1:i0 - 1

          fprintf('%s\n',new_rows.last_name(k))
          new_filter = ...
            new_rows.last_name(k) == ...
            app.stuprograms.last_name & ...
            new_rows.submission_name(k) == ...
            app.stuprograms.submission_name;

          % replace this k value if there was an entry match
          idx_replace(k) = any(new_filter);

          idx_filter = idx_filter | new_filter;

        end

        % Overwrite duplicate entries
        app.stuprograms(idx_filter,:) = new_rows(idx_replace,:);

        % What we do not overwrite, we concatenate
        app.stuprograms = ...
          [app.stuprograms; new_rows(~idx_replace,:)];

      end

      % Remove duplicate new builtins from the collection
      builtin_collection(L_idx:end) = [];
      builtin_collection = unique(builtin_collection);

      builtin_data = new_rows(:, ...
        [...
        "matched_key_name" ...
        "course" ...
        "hw" ...
        "builtins" ...
        ]);

      %create_new_builtins(app, builtin_collection)
      create_new_builtins(app, builtin_data)

      % Save the changes to the csv file
      data_save_datatable(app, 'stuprograms')

      % delete the temporary folder and check the status of this
      % action.
      rmdir(temp_folder,'s');

      close(progressbar);

    end

    %-----------------------------------------------------------------
    function create_new_builtins(app, new_builtins)

      % Get the unique program, hw, and course names
      p_names = unique(new_builtins.matched_key_name);

      % for each name get the builtins
      for k = 1:length(p_names)

        fltr = ...
          new_builtins.course == app.home.course & ...
          new_builtins.hw == app.home.hw & ...
          new_builtins.matched_key_name == p_names(k);

        prog_builtins = new_builtins(fltr,:);

        builtin_list = ...
          rmmissing( ...
          categorical( ...
          unique( ...
          prog_builtins.builtins(:) ...
          )));

        n_new = length(builtin_list);

        if height(app.builtins) == 0
          next_id = 1;
        else
          next_id = app.builtins.builtin_id(end) + 1;
        end

        % Construct new hw row with defaults
        new_rows = table();
        new_rows.builtin_id = (next_id : next_id + n_new - 1)';
        new_rows.name = builtin_list;
        new_rows.authorized = false(n_new,1);
        new_rows.ignore = false(n_new,1);
        new_rows.standard = false(n_new,1);
        new_rows.penalty = zeros(n_new,1);
        new_rows.date_added = repmat(date, n_new, 1);
        new_rows.course = repmat(app.home.course, n_new, 1);
        new_rows.hw = repmat(app.home.hw, n_new, 1);
        new_rows.program = repmat(p_names(k), n_new, 1);

        % Is this the first testcase being added to the database?
        if height(app.builtins) == 0

          % Just set the table to the new rows
          app.builtins = new_rows;

        else

          % Concatenate the new rows
          app.builtins = [app.builtins; new_rows];

        end

      end

      % Keep only unique values from the same homework
      [~, keep_rows] = ...
        unique(app.builtins(:,["course","hw","program","name"]));

      app.builtins = app.builtins(keep_rows,:);

      % Save the changes to the csv file
      data_save_datatable(app, 'builtins')

    end

    %-----------------------------------------------------------------
    function create_new_program_test_cases(app, name, path)

      if height(app.testcases) == 0
        next_id = 1;
      else
        next_id = app.testcases.testcase_id(end) + 1;
      end

      % Extract the variable names for new program
      var_names = get_variable_names(app, name, path);
      n_inputs = length(var_names.input);
      input_strs(1,1:10) = "";
      input_strs(1,1:n_inputs) = var_names.input;

      % Construct new hw row with defaults
      new_tc_rows = table();
      new_tc_rows.testcase_id = next_id;
      new_tc_rows.set = 1;
      new_tc_rows.set_value = 1;
      new_tc_rows.input = input_strs;
      new_tc_rows.value(1,1:10) = "";
      new_tc_rows.header = "";
      new_tc_rows.tolerance = 1e-6;
      new_tc_rows.date_added = date;
      new_tc_rows.modified = true;
      new_tc_rows.course = app.home.course;
      new_tc_rows.hw = app.home.hw;
      new_tc_rows.program = categorical({name});

      % Is this the first testcase being added to the database?
      if height(app.testcases) == 0

        % Just set the table to the new rows
        app.testcases = new_tc_rows;

      else

        % Concatenate the new rows
        app.testcases = [app.testcases; new_tc_rows];

      end

      % Save the changes to the csv file
      data_save_datatable(app, 'testcases')

    end
    %-----------------------------------------------------------------
    function create_new_single_test_case(app)

      if height(app.testcases) == 0
        next_id = 1;
      else
        next_id = app.testcases.testcase_id(end) + 1;
      end

      if height(app.cur_tc_prog) == 0
        last_set_num = 0;
      else
        last_set_num = app.cur_tc_prog.set(end);
      end

      next_set_num = last_set_num + 1;

      % Make a copy of the first row as the default for the
      % new testcase
      new_tc_rows = app.cur_tc_hw(1,:);
      n_rows = max(height(new_tc_rows), 1);

      % Construct new hw row with defaults
      new_tc_rows.testcase_id = (next_id : next_id + n_rows - 1)';
      new_tc_rows.set = next_set_num*ones(n_rows, 1);
      new_tc_rows.set_value = ones(n_rows, 1);
      new_tc_rows.header = repmat("", n_rows, 1);
      new_tc_rows.tolerance = ones(n_rows, 1) * 1e-6;
      new_tc_rows.date_added = date;
      new_tc_rows.modified = true;

      % Add this homework to the database
      app.testcases = [app.testcases; new_tc_rows];

      % Save the changes to the csv file
      data_save_datatable(app, 'testcases')

      % Set the new set number
      app.tc_set = next_set_num;

    end
    %-----------------------------------------------------------------
    function create_directory_structure(app, type)

      switch type

        case 'courses'

          mkdir(app.dirs.course)
          mkdir(app.dirs.course,'student_submissions')

        case 'homeworks'

          mkdir(app.dirs.hw)

          stu_directory = app.dirs.students;
          hw = char(app.home.hw);

          % create this hw folder in each student folder
          for k = 1:length(app.cur_students.last_name)

            name = char(app.cur_students.last_name(k));
            % Student folder
            new_stu_hw_dir = [stu_directory '\' name '\' hw];
            mkdir(new_stu_hw_dir,'original\non_m_files');
            mkdir(new_stu_hw_dir,'resubmission\non_m_files');

          end

      end

    end
    %-----------------------------------------------------------------
    function create_new_key_output(app)

      % Create and save a default
      hw_key_struct = struct( ...
        'course',       app.home.course, ...
        'hw',           app.home.hw, ...
        'path',         app.dirs.hw, ...
        'hw_value',    0 ...
        );

      org_path = [app.dirs.hw '\' ...
        char(app.home.hw) '_key_outputs_original.mat'];
      resub_path = [app.dirs.hw '\' ...
        char(app.home.hw) '_key_outputs_resubmission.mat'];

      save(org_path, '-struct', 'hw_key_struct')
      save(resub_path, '-struct', 'hw_key_struct')

      app.hw_key = hw_key_struct;

      % Get the name of the key mat-file
      app.hw_key_name = [...
        char(app.home.hw) '_key_outputs_' ...
        app.home.subtype ...
        '.mat' ...
        ];
      app.hw_key_file = [app.dirs.hw '\' app.hw_key_name];

    end
    %-----------------------------------------------------------------
    function create_new_stu_output(app)

      % Create and save default student results structure
      app.hw_stu = struct( ...
        'last_name',        '', ...
        'course',           app.home.course, ...
        'hw',               app.home.hw, ...
        'path',             '', ...
        'hw_score',         0, ...
        'pts_possible',     0, ...
        'last_grade_date',  date, ...
        'graded',           false ...
        );

    end
    %-----------------------------------------------------------------
    %         function create_new_tc_output_file(app)
    %
    %             % Get the number of testcase sets for this program
    %             n_sets = length(unique(app.cur_tc_prog.set));
    %
    %             % Initialize the testcase code area text
    %             tc_area = "% Test Cases \n";
    %
    %             % Loop through each testcase for the selected program
    %             for set = 1:n_sets
    %
    %                 % Get the input values for this testcase set
    %                 set_filter = app.cur_tc_prog.set == set;
    %                 tc_inputs = app.cur_tc_prog{set_filter, "value"};
    %
    %                 % Remove any missing values
    %                 tc_inputs(ismissing(tc_inputs)) = [];
    %
    %                 % If there is more than one input for this set join
    %                 % them with a comma
    %                 tc_area = tc_area + join(tc_inputs, ' , ') + "\n";
    %
    %             end
    %
    % %             % Update and save the keyprograms data
    % %             id_filter = app.keyprograms.keyprogram_id == key_id;
    % %             app.keyprograms.tc_script(id_filter) = tc_area;
    % %             app.tc_keyprog.tc_script = tc_area;
    % %             data_save_datatable(app, 'keyprograms')
    %
    %             % Display the script text area
    %             tc_area = split(tc_area,"\n");
    %             app.tc_script_area.Value = tc_area;
    %
    %             create_output_generator(app)
    %
    %         end

  end

  % import methods =====================================================
  methods (Access = private)

    %-----------------------------------------------------------------
    function import_data_type(app, type)

      % import the course data if it exists, otherwise create an
      % empty one
      datatable = [app.dirs.data '\' type '.csv'];

      if ~exist(datatable,'file')
        app.(type) = data_default_datatable(app, type);
        data_save_datatable(app, type);
        return
      end

      var_names_types = data_vars_types(app, type);

      % Testcases need to handled separately since the csv stores
      % our string arrays for the input and value column as separate
      % columns.
      if isequal(type, 'testcases')

        % Set the 10 separate input and value columns in the csv
        % to strings
        input_var_names_types(1:10, 1:2) = "";
        input_var_names_types(1, 1:2) = ["input" , "string"];

        for k = 2:10

          input_var_names_types(k,:) = ...
            ["input_" + num2str(k), "string"];

        end

        value_var_names_types(1:10, 1:2) = "";
        value_var_names_types(1, 1:2) = ["value" , "string"];

        for k = 2:10

          value_var_names_types(k,:) = ...
            ["value_" + num2str(k), "string"];

        end

        % Replace rows 4 and 5 with the above var and types.
        var_names_types = ...
          [ ...
          var_names_types(1:3,:);
          input_var_names_types; ...
          value_var_names_types; ...
          var_names_types(6:end,:)
          ];

      end

      % Stuprograms need to handled separately for the same reason
      if isequal(type, 'stuprograms')

        % Set the 10 separate builtin columns in the csv
        % to strings
        builtin_names_types(1:10, 1:2) = "";
        builtin_names_types(1, 1:2) = ["builtin_1" , "string"];

        for k = 2:10

          builtin_names_types(k,:) = ...
            ["builtin_" + num2str(k), "string"];

        end

        % Replace last row.
        var_names_types = ...
          [ ...
          var_names_types(1:end-1,:);
          builtin_names_types; ...
          ];

      end

      opts = delimitedTextImportOptions( ...
        "NumVariables", length(var_names_types));

      % Specify range and delimiter
      opts.DataLines = [2, Inf];
      opts.Delimiter = ",";

      % Specify column names and types
      opts.VariableNames = var_names_types(:,1);
      opts.VariableTypes = var_names_types(:,2);

      % Specify file level properties
      opts.ExtraColumnsRule = "ignore";
      opts.EmptyLineRule = "read";

      % Variable type specific options
      for k = 1:length(opts.VariableTypes)

        % Specify variable properties based on variable type
        switch opts.VariableTypes{k}

          case 'string'

            opts = setvaropts(opts, opts.VariableNames{k}, ...
              "WhitespaceRule", "preserve");

          case 'categorical'

            opts = setvaropts(opts, opts.VariableNames{k}, ...
              "EmptyFieldRule", "auto");

          case 'logical'

            opts = setvaropts(opts,opts.VariableNames{k},...
              "TrueSymbols",["t","true","1"],...
              "FalseSymbols",["f","false","0"]);

            %                     case 'datetime'
            %                         opts = setvaropts(opts, opts.VariableNames{k}, ...
            %                             "InputFormat", "dd-MMM-yyyy");

        end

      end

      % Import the app type table from csv
      app.(type) = readtable(datatable, opts);

      % Is the first type id nan? (ie no values in datatable)
      if isnan(app.(type).([type(1:end-1) '_id'])(1))
        app.(type)(1,:) = [];
      end

      % Are we working with testcases?
      if isequal(type, 'testcases')

        % We need to condense all the separate testcase input and
        % value columns into one input and value column as a
        % string array.
        app.testcases.input = ...
          app.testcases{:,input_var_names_types(:,1)};
        app.testcases.value = ...
          app.testcases{:,value_var_names_types(:,1)};

        % After merge delete the extra columns from the table
        app.testcases(:,input_var_names_types(2:end,1)) = [];
        app.testcases(:,value_var_names_types(2:end,1)) = [];

      end

      % Are we working with stuprograms?
      if isequal(type, 'stuprograms')

        % We need to condense all the separate stuprogram input &
        % value columns into one input and value column as a
        % string array.
        app.stuprograms.builtins = ...
          app.stuprograms{:,builtin_names_types(:,1)};

        % After merge delete the extra columns from the table
        app.stuprograms(:,builtin_names_types(:,1)) = [];

      end

      % Are we working with stuprograms?
      if isequal(type, 'builtins')

        app.builtins.name = categorical( ...
          replace(string(app.builtins.name), "TRUE", "true") ...
          );

        app.builtins.name = categorical( ...
          replace(string(app.builtins.name), "FALSE", "false") ...
          );

      end

    end
    %-----------------------------------------------------------------
    function import_program(app)

      % Set program directories and settings mat
      app.dirs.program = [app.dirs.hw '\' app.program_name];
      %             app.dirs.program_data = [app.dirs.program '\program_data'];
      app.program_settings = readtable([app.dirs.program ...
        '\program_settings.csv']);

      % Check if program folder is missing
      if ~exist(app.dirs.program,'dir')
        % delete this program from program_list
        programs = app.hw_settings.program_list;
        programs(strcmp(programs,app.program_name)) = [];
        app.hw_settings.program_list = programs;
        save_data(app,'hw','settings');
        return
      end

      % Display key programs
      display_key_programs(app);

    end
    %-----------------------------------------------------------------
    function import_student(app)

      % set student directory
      app.dirs.student = ...
        [app.dirs.students '\' char(app.home.student)];

      % display the currently selected student submission files
      if ~isempty(app.home.hw)
        display_stu_programs(app)
      end

      %             display_stu_programs(app);

      % set student results table

      % display student submitted programs
      %             display_stu_programs(app);

      % import built-in functions used in the selected program
      %             update_builtin_functions_table(app,false)

    end
    %-----------------------------------------------------------------
    function import_hw_submissions(app)

      % Check to see if a course is selected
      if isempty(app.home.course)

        msgbox('Please select a course from the table.')
        return

      end

      % Check to see if a hw set is selected
      if isempty(app.home.hw)

        msgbox('Please select a homework set from the table.')
        return

      end

      % Check to see if there are key programs listed
      if height(app.home_keyprogs_table.Data) == 0

        msgbox( [...
          'Please import key programs before importing ' ...
          'student submissions.' ...
          ])
        return

      end

      % Get submission zip file from user
      [file,path] = uigetfile('*.zip');

      if file == 0
        return
      end

      app.submission_file = [path file];

      create_new_stu_programs(app)

    end
    %-----------------------------------------------------------------
    function import_results(app)

      % set current results struct
      results_dir = [app.dirs.course '\Data\results.mat'];
      if exist(results_dir,'file')
        app.results = open(results_dir);
      else
        app.results = struct();
        save_data(app,'hw','results')
      end

    end
    %-----------------------------------------------------------------
    function import_key_programs(app)

      % Check to see if a course set is selected
      if isempty(app.home.course)
        msgbox('Please select a course.')
        return
      end

      % Check to see if a Homework set is selected
      if isempty(app.home.hw)
        msgbox('Please select a homework set.')
        return
      end

      %             % Message to user
      %             uiwait(msgbox([ 'Navigate to the folder that contains ' ...
      %                 'the key programs. You may select one or more files'] ...
      %                 ,'Key Programs','modal'));

      % Get filenames from user
      [files, path] = uigetfile(...
        {'*.m';'*.mlx';'*.mat';'*.mlapp'},...
        'Select One or More Files', 'MultiSelect', 'on');

      % --------------------------------------
      % TO DO:
      % Implement zip and folder selection
      % --------------------------------------
      app.imported_keys.files = files;
      app.imported_keys.path = path;

    end
    %-----------------------------------------------------------------
    function mat_struct = import_stu_results_mat(app, lname, fname)

      mat_path = ...
        [ ...
        app.dirs.students '\' lname '\' ...
        app.home.hw '\' app.settings.last_sub_type ...
        ];

      mat_file = ...
        [ ...
        mat_path '\' ...
        'results_' lname '_' app.home.hw '.mat' ...
        ];

      % Does the results file exists for this student?
      if exist(mat_file, 'file')

        mat_struct = load(mat_file);

      else

        % Default structure
        mat_struct = struct( ...
          'last_name',    lname, ...
          'first_name',   fname, ...
          'course',       app.home.course, ...
          'hw',           app.home.hw, ...
          'path',         mat_path, ...
          'hw_score',     0, ...
          'graded',       false, ...
          'grade_date',   '' ...
          );

        % Loop through each key program
        for k = 1:height(app.cur_keyprograms)

          key_name = char(app.cur_keyprograms.program(k));

          % Set program specific defaults
          mat_struct.(key_name) = ...
            struct( ...
            'stuprogram', '', ...
            'mis_titled', '', ...
            'late', false, ...
            'submission_type', app.settings.last_sub_type, ...
            'testcase_num', 1, ...
            'output', [], ...
            'correct', true, ...
            'points', 0 ...
            );

        end

      end

      save(mat_file, '-struct', 'mat_struct')


    end
    %-----------------------------------------------------------------

  end

  % data methods =======================================================
  methods (Access = private)

    function var_names_types = data_vars_types(~, type)

      switch type

        case 'courses'

          var_names_types = [ ...
            "course_id"     , "double"; ...
            "name"          , "categorical"; ...
            "date_added"    , "string"; ...
            ];

        case 'students'

          var_names_types = [ ...
            "student_id"    , "double"; ...
            "last_name"     , "categorical"; ...
            "first_name"    , "categorical"; ...
            "full_name"     , "string"; ...
            "date_added"    , "string";
            "course"        , "categorical"; ...
            ];

        case 'homeworks'

          var_names_types = [ ...
            "homework_id"   , "double"; ...
            "name"          , "categorical"; ...
            "pt_value"      , "double"; ...
            "date_added"    , "string";
            "course"        , "categorical"; ...
            ];

        case 'keyprograms'

          var_names_types = [ ...
            "keyprogram_id" , "double"; ...
            "program"       , "categorical"; ...
            "prefix"        , "categorical"; ...
            "m_name"        , "string"; ...
            "pt_value"      , "double"; ...
            "n_inputs"      , "double"; ...
            "n_outputs"     , "double"; ...
            "n_sets"        , "double"; ...
            "manual_grade"  , "logical"; ...
            "date_added"    , "string";
            "course"        , "categorical"; ...
            "hw"            , "categorical"; ...
            ];

        case 'stuprograms'

          var_names_types = [ ...
            "stuprogram_id"     , "double"; ...
            "submission_name"   , "string"; ...
            "expected_name"     , "string"; ...
            "name_in_file"      , "string"; ...
            "file_ext"          , "categorical"; ...
            "file_type"         , "categorical"; ...
            "matched_key_name"  , "categorical"; ...
            "mis_titled"        , "logical"; ...
            "resubmission"      , "logical"; ...
            "late"              , "logical"; ...
            "date_added"        , "string";
            "course"            , "categorical"; ...
            "hw"                , "categorical"; ...
            "last_name"         , "categorical"; ...
            "first_name"        , "categorical"; ...
            "builtins"          , "string";
            ];

        case 'testcases'

          var_names_types = [ ...
            "testcase_id"   , "double"; ...
            "set"           , "double"; ...
            "set_value"     , "double"; ...
            "input"         , "string"; ...
            "value"         , "string"; ...
            "header"        , "string"; ...
            "tolerance"     , "double"; ...
            "date_added"    , "string";
            "modified"      , "logical";
            "course"        , "categorical"; ...
            "hw"            , "categorical"; ...
            "program"       , "categorical"; ...
            ];

        case 'results'

          var_names_types = [ ...
            "result_id"         , "double"; ...
            "last_name"         , "categorical"; ...
            "first_name"        , "categorical"; ...
            "course"            , "categorical"; ...
            "hw"                , "categorical"; ...
            "score"             , "logical"; ...
            "comments"          , "string";
            "deductions"        , "string";
            "submission_type"   , "categorical"; ...
            "effort"            , "categorical";
            "date_added"        , "string";
            ];

        case 'builtins'

          var_names_types = [ ...
            "builtin_id"    , "double"; ...
            "name"          , "categorical";
            "authorized"    , "logical"; ...
            "ignore"        , "logical"; ...
            "standard"      , "logical"; ...
            "penalty"       , "double"; ...
            "date_added"    , "string";
            "course"        , "categorical"; ...
            "hw"            , "categorical"; ...
            "program"       , "categorical"; ...
            ];

      end

    end
    %-----------------------------------------------------------------
    function matfile = data_default_datatable(app, type, rows)

      if nargin < 3
        rows = 0;
      end

      var_names_types = data_vars_types(app, type);

      matfile = table( ...
        'Size', [rows height(var_names_types)], ...
        'VariableNames', var_names_types(:,1), ...
        'VariableTypes', var_names_types(:,2) ...
        );

    end
    %-----------------------------------------------------------------
    function data_save_datatable(app, type)

      csv_file = [app.dirs.data '\' type '.csv'];
      writetable(app.(type), csv_file);

    end
    %-----------------------------------------------------------------
    function data_save_mat_file(app, type)

      switch type

        case 'settings'

          settings_file = [app.dirs.data '\settings.mat'];
          last_course = app.settings.last_course;
          last_hw = app.settings.last_hw;
          last_sub_type = app.settings.last_sub_type;
          save(settings_file, ...
            'last_course', ...
            'last_hw', ...
            'last_sub_type' );

        case 'course_settings'

          settings_file = [app.dirs.course '\' ...
            char(app.home.course) '_settings.mat'];
          std_builtins = app.course_settings.std_builtins;
          save(settings_file, 'std_builtins' );

        case 'hw_key'

          temp_struct = app.hw_key;
          save(app.hw_key_file, '-struct', 'temp_struct')

        case 'hw_stu'

          temp_struct = app.hw_stu;
          save(app.hw_stu_file, '-struct', 'temp_struct')

      end


    end
    %-----------------------------------------------------------------
    function data_filter_tcs(app, program, set)

      % Filter the testcases table by course and hw
      course_filter = app.testcases.course == app.home.course;
      hw_filter = app.testcases.hw == app.home.hw;

      % Course and hw known
      app.cur_tc_hw = ...
        app.testcases(course_filter & hw_filter,:);

      % Course, hw, and program known
      if nargin > 1

        app.tc_keyprog = program;
        tc_prog_filter = app.cur_tc_hw.program == program;
        key_prog_filter = app.cur_keyprograms.program == program;

        % Program specific test cases
        app.cur_tc_prog = app.cur_tc_hw(tc_prog_filter,:);

        % Specific Key Program Table row
        app.tc_keyprog_row = ...
          app.cur_keyprograms(key_prog_filter,:);

      end

      % Course, hw, program, and set known
      if nargin > 2

        %                 app.tc_set = string(set);
        app.tc_set = set;
        set_filter = app.cur_tc_prog.set == app.tc_set;

        % Set specific test case
        app.cur_tc_set = app.cur_tc_prog(set_filter,:);

      end

    end
    %-----------------------------------------------------------------
    function mat_struct = create_key_mat(app)

      % Get all need info to create the results file
      mat_path = app.dirs.hw;
      mat_name = ...
        [app.home.hw '_key_outputs' app.settings.last_sub_type '.mat'];
      mat_file = [mat_path '\' mat_name];

      % Does the results file exists?
      if exist(mat_file, 'file')

        mat_struct = load(mat_file);

      else

        % Get the list of selected key programs to grade
        sel_keys = app.selected_key_progs;

        % Default structure
        mat_struct = struct( ...
          'course',       app.home.course, ...
          'hw',           app.home.hw, ...
          'path',         mat_path, ...
          'total_points', 0 ...
          );

        rmpath(app.dirs.hw)

        % Loop through each program that is being graded
        for j = 1:height(sel_keys)

          key = char(sel_keys.program(j));

          key_path = [app.dirs.hw '\' key];

          % We need to set the current testcase table
          data_filter_tcs(app, key)

          tc_tbl = app.cur_tc_prog;

          prog_pts = sel_keys.pt_value(j);

          cols_to_use = [ ...
            "set" ; ...
            "set_value" ; ...
            "tolerance" ; ...
            ];

          n_rows = height(tc_tbl);
          n_sets = round(str2double(string(tc_tbl.set(end))));
          n_inputs = round(n_rows/n_sets);

          [~, keep_idx] = unique(tc_tbl(:,"set"),'rows');

          tc_info = table2struct(tc_tbl(keep_idx,cols_to_use));

          for set = 1:n_sets

            tc_tbl_set = tc_tbl(tc_tbl.set == set_str, :);

            for i = 1:n_inputs

              input_name = tc_tbl_set.input_name;
              input_value = tc_tbl_set.input_value;

              tc_info(set).inputs.(input_name) = input_value;
            end

          end

          addpath(key_path)
          fun_handle = str2func(key);
          rmpath(key_path)

          mat_struct.(key) = ...
            struct( ...
            'program',          key, ...
            'fun_handle',       fun_handle, ...
            'testcases',        tc_info, ...
            'program_points',   prog_pts, ...
            'date_created',     date, ...
            'ready',            false ...
            );

          mat_struct.(key) = ...
            get_function_output(app, mat_struct.(key));

          mat_struct.(key).ready = true;

        end

        save(mat_file, '-struct', 'mat_struct')

      end

    end
    %-----------------------------------------------------------------
    function update_hw_key(app, keyinfo)


      %             % Generate the outputs for the new testcases
      %             for k = 1:height(app.tc_keyprog)
      %
      %                 % Update the output generator
      %                 create_output_generator(app, keyinfo)
      %
      %                 % Set key info
      %                 key_name = char(app.tc_keyprog.program(k));
      %                 key_prefix = char(app.tc_keyprog.prefix(k));
      %                 key_file = [key_name '_key'];
      %                 key_pts = app.tc_keyprog.pt_value(k);
      %
      %                 % Set output function info
      %                 output_func_name = [key_prefix '_key_outputs'];
      %
      %                 % Add the hw folder to the MATLAB path
      %                 addpath([app.dirs.hw '\' key_name])
      %
      %                 % Get function handle for the output program
      %                 output_func = str2func(output_func_name);
      %
      %                 % Get the new outputs
      %                 key_outputs = output_func(key_file);
      %
      %                 key_filter = app.cur_tc_hw.program == key_name;
      %
      %                 cols = [ ...
      %                     "set", ...
      %                     "set_value", ...
      %                     "input", ...
      %                     "value", ...
      %                     "header", ...
      %                     "tolerance" ...
      %                     ];
      %
      %                 tc_struct(1:length(key_outputs), 1) = struct();
      %
      %                 key_tbl = app.cur_tc_hw(key_filter, cols);
      %
      %                 for set = 1:length(key_outputs)
      %
      %                     tc_struct(set).output = key_outputs(set,:)';
      %                     tc_struct(set).set_value = key_tbl.set_value(set);
      %                     tc_struct(set).tolerance = key_tbl.tolerance(set);
      %
      %                 end
      %
      %                 app.hw_key.(key_name).tc = tc_struct;
      %                 app.hw_key.(key_name).prog_pts = key_pts;
      %
      %                 % Remove the hw folder to the MATLAB path
      %                 rmpath([app.dirs.hw '\' key_name])
      %
      %                 clear tc_struct
      %
      %             end
      %
      %             app.hw_key.hw_points = sum(app.cur_keyprograms.pt_value);
      %
      %             data_save_mat_file(app, 'hw_key')

    end

    %-----------------------------------------------------------------

  end

  % display methods ====================================================
  methods (Access = private)

    function display_roster(app, type)

      num_of_students = height(app.cur_students);

      % display the roster only if a student exists in the roster
      if num_of_students > 0

        switch type

          case 'new'

            % Pre-allocate the table
            app.Course_Roster_Table.Data = ...
              cell(num_of_students,6);

            % Uncheck all boxes
            app.Course_Roster_Table.Data(:,1) = {false};

            % Set the student list
            app.Course_Roster_Table.Data(:,2) = ...
              cellstr(app.cur_students.last_name);

            % set the current student as the first student
            app.Course_Roster_Table.Selection = [1 2];
            app.Course_Roster_Table.Data(1,1) = {true};
            app.student_name = ...
              app.Course_Roster_Table.Data{1,2};

          case 'refresh'

            % Set the student list
            app.Course_Roster_Table.Data(:,2) = ...
              cellstr(app.cur_students.last_name);

        end

        selection = [app.Course_Roster_Table.Data{:,1}];

        % Set the selected students table
        app.selected_students = app.cur_students(selection, :);

      end

    end
    %-----------------------------------------------------------------
    function display_key_programs(app, type)

      num_of_programs = height(app.cur_keyprograms);

      if num_of_programs > 0

        switch type

          case 'new'

            % Pre-allocate the table
            app.home_keyprogs_table.Data = ...
              cell(num_of_programs,2);

            % Check all boxes
            app.home_keyprogs_table.Data(:,1) = {true};

            % Set the program list
            app.home_keyprogs_table.Data(:,2) = ...
              cellstr(app.cur_keyprograms.program);

          case 'refresh'

            % Set the program list
            app.home_keyprogs_table.Data(:,2) = ...
              cellstr(app.cur_keyprograms.program);

        end

        selection = [app.home_keyprogs_table.Data{:,1}];

        % Set the selected keys table
        app.selected_key_progs = app.cur_keyprograms(selection, :);

      else

        % state that there are no programs
        app.home_keyprogs_table.Data = ...
          {[], 'No key programs imported for this homework'};

      end

    end
    %-----------------------------------------------------------------
    function display_stu_programs(app, type)

      num_of_programs = height(app.cur_stuprograms);

      if num_of_programs > 0

        switch type

          case 'new'

            % Pre-allocate the table
            app.Programs_Submitted_Table.Data = ...
              cell(num_of_programs,4);

            % Check all boxes
            app.Programs_Submitted_Table.Data(:,1) = {true};

            % Set the program list
            app.Programs_Submitted_Table.Data(:,2) = ...
              cellstr(app.cur_stuprograms.submission_name);

            % Set the matched key program list
            app.Programs_Submitted_Table.Data(:,3) = ...
              cellstr(app.cur_stuprograms.matched_key_name);

            % Set the mis-titled program check boxes
            app.Programs_Submitted_Table.Data(:,4) = ...
              num2cell(~app.cur_stuprograms.mis_titled);

          case 'refresh'

            % Set the program list
            app.Programs_Submitted_Table.Data(:,2) = ...
              cellstr(app.cur_stuprograms.submission_name);

            % Set the matched key program list
            app.Programs_Submitted_Table.Data(:,3) = ...
              cellstr(app.cur_stuprograms.matched_key_name);

            % Set the mis-titled program check boxes
            app.Programs_Submitted_Table.Data(:,4) = ...
              num2cell(~app.cur_stuprograms.mis_titled);

        end

        selection = [app.Programs_Submitted_Table.Data{:,1}];

        % Set the selected students table
        app.selected_stu_progs = app.cur_stuprograms(selection, :);

      else

        % state that there are no programs
        app.Programs_Submitted_Table.Data = ...
          {[], 'No student programs imported for this homework'};

      end

    end
    %-----------------------------------------------------------------
    function display_tc_programs(app, prog_row)

      % Remove the changing styles from this table
      if height(app.tc_programs_table.StyleConfigurations) > 1
        removeStyle(app.tc_programs_table, ...
          2:height(app.tc_programs_table.StyleConfigurations))
      end

      % Display the programs table
      app.tc_programs_table.Data = ...
        app.cur_keyprograms(:,["program","pt_value"]);

      % Display program values as a rounded string
      app.tc_programs_table.Data.pt_value = ...
        cellstr( ...
        pad( ...
        string( app.tc_programs_table.Data{:,2} ), ...
        5, 'left' ) ...
        );

      % Add new styles
      addStyle(app.tc_programs_table, ...
        app.styles.bg_blue, "row", prog_row);

      display_tc_sets(app, 1)

      display_tc_script(app)

      display_tc_commands(app)

    end
    %-----------------------------------------------------------------
    function display_tc_sets(app, set_row)

      % Remove the changing styles from this table
      if height(app.tc_cases_table.StyleConfigurations) > 1
        removeStyle(app.tc_cases_table, ...
          2:height(app.tc_cases_table.StyleConfigurations))
      end

      % Display the test case table
      app.tc_cases_table.Data = ...
        app.cur_tc_prog(:, ["set", "set_value", "tolerance"]);

      app.tc_cases_table.Data = ...
        sortrows(app.tc_cases_table.Data, "set");

      % Insert "add new" row
      new_row = table(0, "", "");
      new_row.Properties.VariableNames = ...
        app.tc_cases_table.Data.Properties.VariableNames;
      app.tc_cases_table.Data = [app.tc_cases_table.Data ; new_row];

      n_sets = height(app.tc_cases_table.Data);

      % Add new styles
      addStyle(app.tc_cases_table, ...
        app.styles.add, "cell", [n_sets 1]);
      addStyle(app.tc_cases_table, ...
        app.styles.bg_blue, "row", set_row);

      % Display the inputs table
      if height(app.cur_tc_set) > 0
        n = app.tc_keyprog_row.n_inputs;
        app.tc_inputs_table.Data = [ ...
          strip(app.cur_tc_set.input(1:n))' ...
          strip(app.cur_tc_set.value(1:n))' ...
          ];
      end

    end
    %-----------------------------------------------------------------
    function display_tc_script(app)

      % Initialize the testcase code area text
      app.tc_text_area = "% Test Cases";

      % Get the input values for this program
      tbl_values = app.cur_tc_prog{:, "value"};

      % Grab the columns up to the number of inputs for this program
      tc_inputs = tbl_values(:,1:app.tc_keyprog_row.n_inputs);

      % Do multiple inputs exist for this program?
      if app.tc_keyprog_row.n_inputs > 1

        % Join them with commas
        tc_inputs = join(tc_inputs, ' , ');

      end

      % Concatenate tc_area with tc_inputs
      if ~isempty(tc_inputs)
        app.tc_text_area = [app.tc_text_area; tc_inputs];
      end

      % Display the script text area
      app.tc_script_area.Value = app.tc_text_area;

    end
    %-----------------------------------------------------------------
    function display_tc_commands(app)

      % Get the program header commands and display them
      if height(app.cur_tc_set) > 0

        tc_headers = app.cur_tc_set{:, "header"};
        app.tc_commands_area.Value = tc_headers;

      end

    end
    %-----------------------------------------------------------------
    function display_interface(app, table, type)

      switch table

        case 'courses'

          app.CourseDropDown.Items = app.courses.name;
          app.home_courses_table.Data = app.courses(:,"name");

        case 'students'

          load_student(app)
          display_roster(app, type)

        case 'homeworks'

          app.HomeworkDropDown.Items = app.homeworks.name;

        case 'keyprograms'

          display_key_programs(app, type)
          app.HomeworkDropDown.Items = app.homeworks.name;
          app.HomeworkDropDown.Value = app.home.hw;

        case 'stuprograms'

          display_stu_programs(app, type)
      end

    end
    %-----------------------------------------------------------------
    function display_home_page(app)

      % Display and Select Course Data
      app.home_courses_table.Data = app.courses(:,"name");
      row = find(app.courses.name == app.home.course);
      removeStyle(app.home_courses_table)
      addStyle(app.home_courses_table, ...
        app.styles.bg_blue, "row", row)

      % Display and Select Roster Data
      app.home_roster_table.Data = app.cur_students(:,"last_name");
      row = find(app.cur_students.last_name == app.home.student);
      removeStyle(app.home_roster_table)
      addStyle(app.home_roster_table, ...
        app.styles.bg_blue, "row", row)

      % Display and Select Homework Data
      app.home_homeworks_table.Data = app.cur_homeworks(:,"name");
      row = find(app.cur_homeworks.name == app.home.hw);
      removeStyle(app.home_homeworks_table)
      addStyle(app.home_homeworks_table, ...
        app.styles.bg_blue, "row", row)

      % Display and Select Assigned Programs Data
      app.home_keyprogs_table.Data = ...
        app.cur_keyprograms(:,"program");
      row = find(app.cur_keyprograms.program == app.home.keyprog);
      removeStyle(app.home_keyprogs_table)
      addStyle(app.home_keyprogs_table, ...
        app.styles.bg_blue, "row", row)

      % Display and Select Submitted Programs Data
      app.home_stuprogs_table.Data = ...
        app.cur_stuprograms(:, ...
        [ ...
        "submission_name", ...
        "matched_key_name", ...
        "mis_titled", ...
        "late" ...
        ]);

      row = find( ismember( ...
        app.cur_stuprograms.submission_name, app.home.stuprog ...
        ));

      if length(row) > 1 && ...
          ~isempty(app.home_stuprogs_table.Selection)
        row = ...
          row(ismember(row, app.home_stuprogs_table.Selection));
      end

      removeStyle(app.home_stuprogs_table)

      % Show selection highlight
      if isempty(row)
        addStyle(app.home_stuprogs_table, ...
          app.styles.bg_blue, "row", 1)
      else
        addStyle(app.home_stuprogs_table, ...
          app.styles.bg_blue, "row", row)
      end

      keys = app.cur_keyprograms.program;
      matched = app.cur_stuprograms.matched_key_name;

      app.home_stuprogs_table.Data.matched_key_name = ...
        categorical(matched, keys, 'Ordinal', true);

      app.home_sub_original_button.Value = ~app.home.resub;

      if app.home.resub
        app.home_import_submissions.Text = ...
          'Import Student Resubmissions';
      else
        app.home_import_submissions.Text = ...
          'Import Student Submissions';
      end

    end

    function display_grading_page(app)

      % Invalid selection?
      if isempty(app.grading.sel_rows) || ...
          isempty(app.grading.sel_key_rows)

        % Don't do anything
        return

      end

      % Filter all selected students
      stu_filter = ismember( ...
        app.grading.program.last_name, app.grading.sel_studs);

      %             prog_filter = ismember( ...
      %                 app.grading.program.matched_key_name, ...
      %                 app.grading.sel_keys.program ...
      %                 );

      app.grading.stu_progs = app.grading.program(stu_filter,:);

      % Remove and add new highlight for selected student
      removeStyle(app.grading_student_table)
      addStyle(app.grading_student_table, ...
        app.styles.bg_blue, "row", app.grading.sel_rows)

      % Remove and add new highlight for selected student
      removeStyle(app.grading_program_table)
      addStyle(app.grading_program_table, ...
        app.styles.bg_blue, "row", app.grading.sel_key_rows)

      % Populate Program Table for the selected student
      app.grading_program_table.Data = ...
        string(app.grading.key_progs.program);

      % Get builtins for the selected student and programs
      if height(app.grading.stu_progs) > 0

        stu_builtins = app.grading.stu_progs( ...
          ismember( ...
          app.grading.stu_progs.matched_key_name, ...
          app.grading.sel_keys.program ...
          ),:).builtins;

        % Remove missing and duplicate values
        stu_builtins = ...
          rmmissing(categorical(unique(stu_builtins(:))));

      else

        stu_builtins = "";

      end

      % Filter the builtins table
      fltr1 = app.builtins.course == app.home.course & ...
        app.builtins.hw == app.home.hw & ...
        ismember(app.builtins.name, stu_builtins) & ...
        ismember(app.builtins.program, ...
        app.grading.sel_keys.program) & ...
        ~app.builtins.standard;

      cur_builtins_table = app.builtins(fltr1, ...
        ["name","authorized","ignore","standard","penalty"] ...
        );

      % Display built-ins
      app.grading_builtin_detected_table.Data = cur_builtins_table;

    end

  end

  % other methods ======================================================
  methods (Access = private)

    function list = get_stu_programs(app)

      hw_dir = [app.dirs.student '\' app.home.hw];
      submission_dir = [hw_dir '\' app.settings.last_sub_type];

      % make sure the hw folder exists
      if exist(hw_dir,'dir')
        % get submission folder listing
        program_listing = dir(submission_dir);

        % get all file names
        student_files = {program_listing(3:end).name};

        % initialize a logical array for the student files
        files_to_display = true(1,length(student_files));

        % logical index for selected key programs
        key_filter = [app.home_keyprogs_table.Data{:,1}];

        if app.required_programs_only.Value
          files_to_display = contains(...
            student_files,...
            regexprep(...
            app.home_keyprogs_table.Data(key_filter,2)...
            ,'_key',''));
        end
        list = student_files(files_to_display)';
      else
        list = {};
      end

    end
    %-----------------------------------------------------------------
    function [name,found] = match_to_key(~,filename,lastname)

      found = false;

      % get the lower the case version og the filename
      filename_lower = lower(erase(filename,'.m'));

      % number of chars in the student's lastname
      num_chars = length(lastname);

      % get the index of the start of the student's name
      name_idx = strfind(filename_lower,lower(lastname));

      % save the name they used
      name_used = filename( ...
        name_idx(end) : name_idx(end) + num_chars - 1 ...
        );

      % if the student did not provide a lastname, add it to the end
      % of this file and re-attach the .m
      if contains(filename,['_' lower(lastname)])
        name = [filename '.m'];
      else
        name = [filename '_' lastname '.m'];
      end

      % use the required program names to remove the imported junk
      for k = 1:numel(key_files)
        file = key_files{k};
        if contains(filename_lower,lower(file))
          % set new name and found
          name = [file '_' name_used '.m'];
          found = true;

          return
        end
      end
    end
    %-----------------------------------------------------------------
    function clear_current_properties(app)

      app.course = struct();
      app.home.course = categorical("");
      app.home.hw = categorical("");
      app.hw_num = 0;
      app.dirs.course = '';
      app.dirs.cur_hw = '';
      app.results = struct();
      app.course_roster = table();

    end
    %-----------------------------------------------------------------
    function name = get_function_name(~, path)

      % load the contents of the raw m-file
      contents = fopen( path, 'rt' );

      % get the name of the function from the function
      % header
      text_line = ' ';
      header_found = false;
      while ~header_found && ischar(text_line)

        % get the next line of the file
        text_line = fgetl(contents);

        % get staring index of "function" and "%"
        f_idx = strfind(text_line,'function');
        c_idx = strfind(text_line,'%');

        % Not a function header
        if isempty(f_idx)
          continue
        end

        % use only the first function found on this line
        f_idx = f_idx(1);

        % Not a function header - A comment contains
        % the word "function".
        if ~isempty(c_idx) && f_idx > c_idx
          continue
        end

        header_found = true;

        % Is a function header with a trailing comment
        if isempty(c_idx)
          c_idx = length(text_line);
        end

        % remove the function and trailing comment
        text_line = erase( ...
          text_line(f_idx:c_idx),'function');

        % split line at any equal signs
        equal_split = split(text_line,'=');

        text_line = strtrim(equal_split{end});

        % split line at (
        l_paren_split = split(text_line,'(');

        text_line = l_paren_split{1};

        % remove blank spaces
        text_line = erase(text_line,' ');
      end
      name = text_line;

      fclose(contents);
    end
    %-----------------------------------------------------------------
    function [dist, idx] = find_closest_file_match(~, files, key)

      % Wagner–Fischer algorithm to calculate the
      % edit distance / Levenshtein distance.

      distances = zeros(1,length(files));
      for k = 1:length(files)
        list_file = files{k};
        N1 = 1+numel(list_file);
        N2 = 1+numel(key);

        D = zeros(N1,N2);
        D(:,1) = 0:N1-1;
        D(1,:) = 0:N2-1;

        for r = 2:N1
          for c = 2:N2
            D(r,c) = min([...
              D(r-1,c) + 1, ...
              D(r,c-1) + 1, ...
              D(r-1,c-1) + ~strcmpi(list_file(r-1),key(c-1))...
              ]);
          end
        end
        distances(k) = D(end);
      end
      [dist, idx] = min(distances);
    end
    %-----------------------------------------------------------------
    function var_names = get_variable_names(~, mfile, mpath)

      % Either there was no submission or the program could not be
      % matched
      if isempty(mfile)

        var_names = struct('input',[],'output',[]);
        return

      end

      % Check if path ends with a slash. Add one if not.
      if mpath(end) == '\' || mpath(end) == '/'
        fullpath = [mpath mfile '.m'];
      else
        fullpath = mpath + "\" + mfile + ".m";
      end

      % Temporarily add the programs's directory to the MATLAB path
      % in ordr to get the number of input variables
      sep = pathsep;
      pathStr = [sep path sep];
      on_path  = ...
        contains(pathStr, [sep, mpath, sep], 'IgnoreCase', ispc);

      if on_path

        try

          num_inputs = nargin(str2func(mfile));

        catch stu_error

          msgbox([ ...
            'Error getting the inputs from' char(mfile) ...
            '\n\n' ...
            'Error Message: ' stu_error.message ...
            ])
          return

        end


      else

        addpath(mpath)
        num_inputs = nargin(str2func(mfile));
        rmpath(mpath)

      end

      % Read in the m-file
      f_id = fopen(fullpath);

      % scan through the lines of the m-file trimming whitespace
      current_line = strtrim(fgetl(f_id));
      while ischar(current_line)

        % Check if the line is valid function header
        if ~isempty(current_line) ...
            && current_line(1) ~= '%' ...
            && contains(current_line,'function')

          % Extract the input & output variables from this line
          var_names.input = ...
            get_input_variables(current_line,num_inputs);
          var_names.output = ...
            get_output_variables(current_line);

          break
        end

        % Grab the new line and trim the whitespace
        current_line = strtrim(fgetl(f_id));
      end

      % Don't forget to close the m-file
      fclose(f_id);

      % ------------------------------------------------------------
      % Helper Function 1:
      function input_vars = get_input_variables(line, n_inputs)

        if n_inputs > 0

          % Get the location of the equals sign, if it exists
          equal_index = strfind(line,'=');
          if isempty(equal_index)
            equal_index = 1;
          else
            % In case the line contains '=' in a comment
            equal_index = equal_index(1);
          end

          % Attempt to extract the input variables between
          % parentheses via the following sequence of commands:

          % 1. Get everything after the '='
          input_string = line(equal_index:end);

          % 2. Get the location of '('
          left_parens = strfind(input_string,'(');

          % 3. Get the location of ')'
          right_parens = strfind(input_string,')');

          % 4. Get everything between '(' and ')'
          input_string = ...
            input_string(left_parens(1)+1:right_parens(end)-1);

          % 5. Split text by comma to get the variables
          input_vars = split(input_string,',');
        else
          % No input variables
          input_vars = {};
        end

      end

      % ------------------------------------------------------------
      % Helper Function 2:
      function output_vars = get_output_variables(line)

        % remove 'function' from the line
        line = strtrim(replace(line,'function',''));

        % output variables exist if there is an '=' sign
        if contains(line,'=')

          % get the location of the '='
          equal_index = strfind(line,'=');

          % get everything to the left of the '=' sign
          output_string = strtrim(line(1:equal_index-1));

          % Look for a '['
          if contains(line,'[')
            % Get the location of '['
            lb_idx = strfind(line(1:equal_index),'[');

            % Get the location of ']'
            rb_idx = strfind(line(1:equal_index),']');

            % Get everything between '[' and ']'
            output_string = strtrim( ...
              output_string(lb_idx(1)+1 : rb_idx(end)-1));

            if contains(line,',')

              % output variables are separated by commas
              output_vars = split(output_string,',');

            elseif contains(line,' ')

              % output variables are separated by spaces
              output_vars = split(output_string,' ');

            else

              % one output variable in brackets
              output_vars = {output_string};

            end
          else

            % one output variable not in brackets
            output_vars = {output_string};

          end
        else

          % no output variables
          output_vars = {};

        end
      end

    end
    %-----------------------------------------------------------------
    function save_script_area_data(app)

      % Get script area text
      script_text = app.tc_script_area.Value;

      % Delete comments
      script_text(contains(script_text,"%")) = [];

      set_count = 1;

      n_sets = height(app.cur_tc_prog);

      cur_hdr = "";

      n_inputs = app.tc_keyprog_row.n_inputs;

      % Loop through the remaining lines of text area
      for k = 1:length(script_text)

        current_line = script_text{k};

        if ~isempty(current_line)

          temp = current_line;
          l = find(temp == '[', 1);
          r = find(temp == ']', 1);

          % remove any commas between brackets
          while ~isempty(l)
            locs = false(1,length(temp));
            locs(l:r) = true;
            comma_locs = temp == ',' & locs;
            current_line(comma_locs) = ' ';
            temp([l r]) = ' ';
            l = find(temp == '[', 1);
            r = find(temp == ']', 1);
          end

          % Now split by commas to get each input
          values = string(strip(split(current_line, ",")));

          if k > n_sets

            id_idx = app.testcases.testcase_id(end) + 1;

            app.testcases = ...
              [app.testcases; app.testcases(id_filter,:)];

            app.testcases.testcase_id(end) = id_idx;

          elseif n_sets > 0

            id_idx = app.cur_tc_prog.testcase_id(set_count);

          end

          id_filter = app.testcases.testcase_id == id_idx;
          app.testcases.set(id_filter) = set_count;
          app.testcases.value(id_filter,1:n_inputs) = values;

          app.testcases.header(id_filter) = cur_hdr;

          cur_hdr = "";
          set_count = set_count + 1;

        else

          cur_hdr = cur_hdr + current_line;

        end

      end

      % Save the data table
      data_save_datatable(app, 'testcases')

    end
    %-----------------------------------------------------------------
    function create_output_generator(app, keyinfo)

      % Get the file name and path of function
      f_name = [keyinfo.prefix '_output_generator.m'];
      f_path = [app.dirs.hw '\' keyinfo.name];
      f_file = [f_path '\' f_name];

      % Get the number of sets and outputs
      f_n_sets = num2str(height(keyinfo.tcs));
      f_n_outputs = num2str(keyinfo.n_outputs);

      % Get the input values from the current program testcase table
      val_str = keyinfo.tcs.value(:,1:keyinfo.n_inputs);

      % Is there more than one input?
      if keyinfo.n_inputs > 1

        % Join all inputs with a comma
        val_str = join(val_str, ", ");

      end

      % Add indents and multi-line dots to the end of each value
      val_str = "    " + val_str + " ; ...";

      % Convert from string array to cell array of chars
      val_str = cellstr(val_str);

      % Set each line of output generator text
      % Each part below will be vertically concatenated
      text_p1 = { ...
        '% Test Case Input/Output Generator'; ...
        'function [inputs, outputs, error_msg] = ...'; ...
        ['    ' char(keyinfo.prefix) ...
        '_output_generator(func_name)']; ...
        ''; ...
        '    global unresponsive'; ...
        ''; ...
        ['    error_msg(1:' f_n_sets ') = ' ...
        'struct(''unresponsive'', false);']; ...
        ''; ...
        '    % Set the calling function'; ...
        '    if isempty(func_name)'; ...
        '        func_handle = str2func("not_submitted");'; ...
        '    else'; ...
        '        func_handle = str2func(func_name);'; ...
        '    end'; ...
        ''; ...
        '    % Set the input cell array'; ...
        '    inputs = { ... '; ...
        };
      text_p2 = val_str;
      text_p3 = { ...
        '    };'; ...
        ''; ...
        '    % Initialize output cell array'; ...
        ['    outputs = cell(' f_n_sets ',' f_n_outputs ');']; ...
        ''; ...
        '    % Check for no output variables';
        '    if isempty(outputs)'; ...
        '        return'; ...
        '    end'; ...
        ''; ...
        };
      text_p4 = { ...
        '    % Generate Outputs'; ...
        ['    for set = 1:' f_n_sets]; ...
        ''; ...
        '        unresponsive = false;'; ...
        '        try'; ...
        '            [outputs{set,:}] = ...';
        '                func_handle( inputs{set,:} );'; ...
        '        catch err_out'; ...
        '            error_msg(set).matlab_error = err_out;'; ...
        %                 '            continue'; ...
        '        end'; ...
        ''; ...
        '        error_msg(set).unresponsive = unresponsive;'; ...
        '    end'; ...
        ''; ...
        'end' ...
        };

      % Write the get output function
      writelines([text_p1; text_p2; text_p3; text_p4], f_file)

    end
    %-----------------------------------------------------------------
    function script_file_to_area(~)

    end
    %-----------------------------------------------------------------
    function dump_all_files_to_folder(app, source, destination)

      if ~exist(destination,'dir')
        mkdir(destination)
      end

      temp_folders = extract_all_files(app, source, destination, {});

      for k = 1:length(temp_folders)
        if exist(temp_folders{k},'dir')
          rmdir(temp_folders{k},'s')
        end
      end

    end
    %-----------------------------------------------------------------
    function temp_dirs = extract_all_files(app, src, dest, temp_dirs)

      if contains(src,'.zip')
        unzip(src, [src(1:end-4) ' temp'])
        src = [src(1:end-4) ' temp'];
        temp_dirs = [temp_dirs;{src}];
        temp_dirs = ...
          extract_all_files(app, src, dest, temp_dirs);
      elseif isfolder(src)
        dir_list = dir(src);
        dir_list(1:2) = [];
        while ~isempty(dir_list)
          temp_dirs = ...
            extract_all_files(app, ...
            [src '\' dir_list(1).name], dest, temp_dirs);
          dir_list(1) = [];
        end
      else
        copyfile(src, dest);
      end

    end
    %-----------------------------------------------------------------
    function [TF, ID] = isfunction(~, FUN)
      % ISFUNCTION - true for valid matlab functions
      %
      %   TF = ISFUNCTION(FUN) returns 1 if FUN is a function, and 0
      %   otherwise. FUN can be a string or function handle.
      %
      %   [TF, ID] = ISFUNCTION(FUN) also returns an identier ID.
      %   ID can take the following values:
      %      1  : FUN is a function string
      %      2  : FUN is a function handle
      %      0  : FUN is not a function, but no further specification
      %     -1  : FUN is not a function but a script
      %     -2  : FUN is not a valid function m-file (e.g., a matfile)
      %     -3  : FUN does not exist (as a function)
      %     -4  : FUN is not a function but something else (a variable)
      %
      if ~iscell(FUN)
        % we use cellfun, so convert to cells
        FUN = {FUN} ;
      end
      % get the identifier for each "function"
      ID = cellfun(@local_isfunction,FUN) ;

      % valid matlab functions have a positive identifier
      TF = ID > 0 ;
      % = = = = = = = = = = = = = = = = = = = = =
      function ID = local_isfunction(FUNNAME)
        try
          % nargin errors when FUNNAME is not a function
          nargin(FUNNAME);
          % 1 for m-file, 2 for handle
          ID = 1  + isa(FUNNAME, 'function_handle');
        catch ME
          % catch the error of nargin
          switch (ME.identifier)
            case 'MATLAB:nargin:isScript'
              % script
              ID = -1 ;
            case 'MATLAB:narginout:notValidMfile'
              % another type of file, or does not exist
              ID = -2 ;
            case 'MATLAB:narginout:functionDoesnotExist'
              % probably a handle, but not to a function
              ID = -3 ;
            case 'MATLAB:narginout:BadInput'
              % probably a variable or an array
              ID = -4 ;
            otherwise
              % unknown cause for error
              ID = 0 ;
          end
        end
      end
    end
    %-----------------------------------------------------------------
    function clear_home_tab_info(app)

      app.home.course = categorical("");
      app.home.hw = categorical("");
      app.home.keyprog = categorical("");
      app.home.student = categorical("");
      app.home.stuprog = "";
      app.home.stuprog_id = [];

    end

    %-----------------------------------------------------------------
    function update_builtin_functions_table(app, print_to_cmdline)

      % Get builtins for the selected student and programs
      stu_builtins = ...
        app.grading.stu_progs.builtins(app.grading.sel_key_rows,:);

      stu_builtins = rmmissing(stu_builtins(:));

      stu_builtins = unique(stu_builtins);

      %             % Filter builtin functions by course, hw, program
      %             builtin_filter = app.builtins.course == app.home.course;
      %
      %             cur_builtins = app.builtins(builtin_filter, :);


      app.grading_builtin_detected_table.Data = table(stu_builtins);

      %             % Find Built-ins
      %             student_selected = strsplit(app.RosterListBox.Value{1}, ', ');
      %             student_last_name = student_selected{1};
      %             student_files_selected = app.ProgramsSubmittedListBox.Value;
      %
      %             if contains(lower(student_files_selected),"no programs found")
      %                 return
      %             end
      %
      %             if print_to_cmdline
      %                 fprintf('\n============ Built-in functions used. ============ \n')
      %             end
      %
      %             for f_idx = 1:numel(student_files_selected)
      %                 file = student_files_selected{f_idx};
      %                 studentFilePath = [app.Submission_Dir,'\',student_last_name,'\',file];
      %                 parsetree = mtree(studentFilePath, '-file');
      %                 treeids = parsetree.mtfind('Kind', 'ID');
      %                 idstrings = unique(treeids.strings);
      %                 isbuiltin = cellfun(@(id) exist(id, 'builtin') == 5, idstrings);
      %                 nonbuiltinid = idstrings(~isbuiltin);
      %                 builtinid = idstrings(isbuiltin);
      %                 if print_to_cmdline
      %                     fprintf('%s: \n',file)
      %                 end
      %                 for k = 1:numel(builtinid)
      %                     if print_to_cmdline
      %                         fprintf('    %s \n',builtinid{k})
      %                     end
      %                     app.Builtin_Function_Table.Data.Function{k} = builtinid{k};
      %                 end
      %                 extras_to_find = {'num2str','str2num'};
      %                 extras = nonbuiltinid(contains(nonbuiltinid,extras_to_find));
      %                 for k = 1:numel(extras)
      %                     if print_to_cmdline
      %                         fprintf('    %s \n',extras{k})
      %                     end
      %                 end
      %              end
      %              if print_to_cmdline
      %                 fprintf('================================================== \n\n\n\n')
      %              end
    end
    %-----------------------------------------------------------------
    function functions_found = functions_in_function(~, file_path)

      % Get the function dependancy tree and ids
      parsetree = mtree(file_path, '-file');
      treeids = parsetree.mtfind('Kind', 'ID');

      % Remove duplicate values
      func_names = unique(treeids.strings);

      % Are they MATLAB built-in functions?
      isbuiltin = ...
        cellfun(@(id) exist(id, 'builtin') == 5, func_names);

      % Return built-ins and non-built-ins
      functions_found.nonbuiltin = func_names(~isbuiltin)';
      functions_found.builtin = func_names(isbuiltin)';

    end

    % Helper Function
    function c = linewrap(~, s, maxchars)
      %LINEWRAP Separate a single string into multiple strings
      %   C = LINEWRAP(S, MAXCHARS) separates a single string into
      %   multiple strings by separating the input string, S, on word
      %   breaks.  S must be a single-row char array. MAXCHARS is a
      %   nonnegative integer scalar specifying the maximum length of
      %   the broken string.  C is a cell array of strings.
      %
      %   C = LINEWRAP(S) is the same as C = LINEWRAP(S, 80).
      %
      %   Note: Words longer than MAXCHARS are not broken into
      %   separate lines. This means that C may contain strings
      %   longer than MAXCHARS.
      %
      %   Example
      %       s = 'Image courtesy of Joe and Frank Hardy, MIT, 1993.'
      %       c = linewrap(s, 40)

      narginchk(1, 3);
      bad_s = ~ischar(s) || (~ismatrix(s)) || (size(s, 1) ~= 1);
      if bad_s
        error('S must be a single-row char array.');
      end
      if nargin < 3
        % Default value for second input argument.
        maxchars = 80;
      end
      % Trim leading and trailing whitespace.
      s = strtrim(s);
      % Form the desired regular expression from maxchars.
      exp = sprintf( ...
        '(\\S\\S{%d,}|.{1,%d})(?:\\s+|$)', ...
        maxchars, maxchars ...
        );
      % Interpretation of regular expression (for maxchars = 80):
      % '(\\S\\S{80,}|.{1,80})(?:\\s+|$)'
      %
      % Match either a non-whitespace character followed by 80 or
      % more non-whitespace characters, OR any sequence of between 1
      % and 80 characters; all followed by either one or more
      % whitespace characters OR end-of-line.
      tokens = regexp(s, exp, 'tokens').';
      % Each element of the cell array is single-element cell array
      % containing a string. Convert this to an array of strings.
      get_contents = @(f) f{1};
      c = cellfun(get_contents, tokens, 'UniformOutput', false);
      % Remove trailing whitespace characters from strings in c. This
      % happens if multiple whitespace characters separated the last
      % word on a line from the first word on the following line.
      c = deblank(c);

    end

  end

  % key program dropdown action methods ================================
  methods (Access = private)

    function key_prog_action_open(app)

      % Get the location of the user click
      rows_selected = app.home_keyprogs_table.Selection;

      % If no valid rows were selected, ignore click
      if isempty(rows_selected)
        return
      end

      % Construct the selected logical array
      progs_selected = [app.home_keyprogs_table.Data{rows_selected, 1}];

      if isempty(progs_selected)

        msgbox('Please select one or more key programs to open.');

      else

        % Filter the relevant info from the current key program tbl
        prog_subtable = ...
          app.cur_keyprograms(rows_selected, ["program","m_name"]);

        % Construct the string array of program locations
        prog_paths = app.dirs.hw + "\" + ...
          string(prog_subtable.program) + "\" + ...
          prog_subtable.m_name;

        % Open each program with the edit command
        cellfun(@edit, prog_paths)

      end
    end

    function stu_prog_action_open(app)
      % Get the location of the user click
      rows_selected = app.home_stuprogs_table.Selection;

      % If no valid rows were selected, ignore click
      if isempty(rows_selected)
        rows_selected = 1;
      end

      % Construct the selected logical array
      progs_selected = [app.home_stuprogs_table.Data{rows_selected, 1}];

      if isempty(progs_selected)

        msgbox('Please select one or more key programs to open.');

      else

        % Filter the relevant info from the current key program tbl
        prog_subtable = app.cur_stuprograms( ...
          rows_selected, ...
          ["submission_name","file_ext"]);

        % Construct the string array of program locations
        prog_paths = app.dirs.stuprogram + "\" + ...
          app.home.subtype + "\" + ...
          string(prog_subtable.submission_name) + "." + ...
          string(prog_subtable.file_ext);

        % Open each program with the edit command
        cellfun(@edit, prog_paths)
      end

    end
    
    %-----------------------------------------------------------------
    function keyaction_create_p_codes(app)
      %key_files_selected = app.ProgramsRequiredListBox.Value;
      if isempty(app.Selected_Key_Programs)
        msgbox('Please select one or more key programs to create p-codes for.');
      else
        msgbox('need to add this functionality!')
        key_files_selected = app.home_keyprogs_table.Data(app.Selected_Key_Programs,2);
        numberOfFilesSelected = length(key_files_selected);
        for i=1:numberOfFilesSelected
          currentFilePath = [app.Key_Dir,'\',key_files_selected{i},'_key.m'];
          edit(currentFilePath);
        end
      end
    end
    %-----------------------------------------------------------------
    function keyaction_make_key(app)

      addpath(app.Grading_Dir,app.Submission_Dir,app.Key_Dir);

      key_files = app.home_keyprogs_table.Data(:,2);
      student_files_selected = key_files;
      numberOfFilesSelected = length(student_files_selected);

      hw_grader_name = ['HW',app.home.hw,'_Grader'];
      hw_grader_function = str2func(hw_grader_name);

      student_last_name = 'Key';
      student_result_folder = [app.Results_Dir,'\HW_',app.home.hw,'_Results_',student_last_name,'.txt'];

      student_result_fid = fopen(student_result_folder,'w');
      close all; clc;

      fprintf(student_result_fid,'==========================================================================\n');
      fprintf(student_result_fid,'Homework %s\n',student_last_name);
      fprintf(student_result_fid,'==========================================================================\n');

      total_score = 0;
      total_possible = 0;

      app.Student_Result_Table

      for i=1:numberOfFilesSelected

        student_program = student_files_selected{i};
        student_program = erase(student_program,'.m');
        program_title = get_closest_key_file_match(app,key_files,student_program,student_last_name);

        [input_vars,output_vars] = get_variable_names_from_mfile(app,program_title);
        [~,~,prob_score,possible_score] = ...
          hw_grader_function(student_last_name,student_program,program_title,...
          input_vars,output_vars,student_result_fid,0,...
          app.UsePreviousHWProgramsCheckBox.Value,app.GradingResubmissionsCheckBox.Value,app);
        total_score = total_score + prob_score;
        total_possible = total_possible + possible_score;

        default_row = {program_title,prob_score,'','','','','','','','','','','','','','','','','',''};
        app.Student_Result_Table{i,:} = default_row;
      end

      edit(student_result_folder);

      update_student_results_file(app);
    end
    %-----------------------------------------------------------------
  end

  % TC Menu action methods =============================================
  methods (Access = private)

    function tc_menu_delete(app)

      if height(app.cur_tc_prog.set) == 1
        return
      end

      % Get the testcase id we want to delete
      id_to_del = ...
        app.cur_tc_prog.testcase_id( ...
        app.cur_tc_prog.set == app.tc_set ...
        );

      new_row = find(app.tc_cases_table.Data.set == app.tc_set);

      % Delete the selected set from the testcases data
      app.testcases( ...
        app.testcases.testcase_id == id_to_del ...
        , :) = [];

      app.cur_tc_prog( ...
        app.cur_tc_prog.testcase_id == id_to_del ...
        , :) = [];

      n_sets = height(app.cur_tc_prog);

      tc_idx = app.cur_tc_prog.testcase_id;

      % Get the logical array of the sets we need to decrease
      set_idx = ...
        logical(sum(app.testcases.testcase_id == tc_idx', 2));

      % Reset the set numbers
      app.testcases.set(set_idx) = categorical((1:n_sets)');

      % Save the new testcase data
      data_save_datatable(app, 'testcases');

      % Re-populate all subtables
      data_filter_tcs(app, app.tc_keyprog, new_row)

      display_tc_sets(app, new_row)
    end
    %-----------------------------------------------------------------
    function tc_menu_copy(app)

      create_new_single_test_case(app)

      set_select = app.tc_cases_table.Selection;

      data_filter_tcs(app, prog_select, set_select(1))

      % Get the set number to copy from
      set_from = double(app.cur_tc_prog.set(set_select));

      % Get the set number to copy from
      set_to = double(app.cur_tc_prog.set(end));

      % Get the set column from the program subtable
      set_col = double(app.cur_tc_prog.set);

      % Get the testcase ids to copy from
      tc_ids_from = ...
        app.cur_tc_prog(set_col == set_from,:).testcase_id;

      % Get the testcase ids to copy to
      tc_ids_to = ...
        app.cur_tc_prog(set_col == set_to,:).testcase_id;

      from_filter = app.testcases.testcase_id == tc_ids_from;
      to_filter = app.testcases.testcase_id == tc_ids_to;

      var_names = { ...
        'set_value', ...
        'input_value', ...
        'tolerance', ...
        'script'};

      % Delete the test case ids from the main data table
      app.testcases(to_filter,var_names) = ...
        app.testcases(from_filter,var_names);

      data_save_datatable(app, 'testcases');

      % Re-populate all subtables
      data_filter_tcs(app, app.tc_keyprog, set_select)

      display_tc_sets(app, set_select)

    end


  end

  % grading methods ====================================================
  methods (Access = private)

    function grade_program(app, stu_results, key)

      % Get the data student program row that contains the key name
      stu_row = app.cur_stuprograms.matched_key_name == key;
      stu_prog = app.cur_stuprograms.submitted_name(stu_row);

      % Was the student program matched to this key?
      if isempty(stu_prog)

        stu_results.(key).found = false;

      else

        % Get the testcases

        % Scan through each test case

        % Generate the student output for this testcase

        % Compare the student and key outputs

        compare_outputs(stu, key)


      end

    end
    %-----------------------------------------------------------------
    function info_out = get_function_output(app, info_in)

      info_out = info_in;

      % Loop through each testcase
      for k = 1:length(info_in.testcases)

        input_str = struct2cell(info_in.testcases(k).inputs);
        inputs = cellfun(@str2num, cellstr(input_str), 'UniformOutput', false);
        info_out.testcases(k).output = info_in.fun_handle(inputs{:});

      end

    end
    %-----------------------------------------------------------------
    function grade_stu_programs(app)

      progressbar = uiprogressdlg( app.UIFigure, ...
        'Title', 'Progress', ...
        'Message', 'Grading Student Programs', ...
        'Value', 0, ...
        'ShowPercentage', 'on', ...
        'Cancelable','on');

      % Before grading, update any modified testcases
      key_data = update_key_testcase_output(app, true);

      sel_students = string(app.grading.sel_studs);

      % Add key HW directory to the MATLAB path
      addpath(genpath(app.dirs.hw))

      % Filter builtins table
      bltin_fltr = app.builtins.course == app.home.course & ...
        app.builtins.hw == app.home.hw;

      all_bltins_tbl = app.builtins(bltin_fltr,:);

      % Loop through each selected student
      for k = 1:length(sel_students)

        % Set student info
        stu_name = char(sel_students(k));
        stu_path = [ ...
          app.dirs.students '\' ...
          stu_name '\' ...
          char(app.home.hw) '\' ...
          app.settings.last_sub_type ...
          ];

        % Update the progress bar
        progressbar.Value = k/length(sel_students);
        stu_grading_text = "Grading " + stu_name + "'s Programs";
        progressbar.Message = stu_grading_text;


        % Check for clicked Cancel button
        if progressbar.CancelRequested
          break
        end

        addpath(stu_path)

        % Set the results file info for this student
        hw_stu_name = [ ...
          char(app.home.hw) '_' stu_name '_results_' ...
          app.settings.last_sub_type '.mat' ...
          ];
        hw_stu_file = [stu_path '\' hw_stu_name];

        % Create new student results structure
        stu_results = struct( ...
          'last_name',        stu_name, ...
          'course',           app.home.course, ...
          'hw',               app.home.hw, ...
          'path',             stu_path, ...
          'hw_score',         0, ...
          'hw_value',         key_data.hw_value, ...
          'last_grade_date',  date, ...
          'graded',           false ...
          );

        for j = 1:height(app.grading.sel_keys)

          % Set key program information
          keyprog = char(app.grading.sel_keys.program(j));
          keyprefix = char(app.grading.sel_keys.prefix(j));
          %                     keypath = [app.dirs.hw '\' keyprog];

          progressbar.Message = stu_grading_text + ...
            ":  " + string(keyprog);

          if ~isfield(key_data, keyprog)

            % cannot grade selected program
            msgbox([ ...
              'The grading app cannot find test cases ' ...
              'for the key program "' keyprog '". ' ...
              'Create some test cases for this function ' ...
              'on the "Test Cases" tab before grading.' ...
              ])

            rmpath(stu_path)

            return

          end

          %                     if app.grading.sel_keys.n_outputs(j) == 0
          %
          %                         % No output variables for this program. Skip it.
          %                         continue
          %
          %                     end

          tcs_filter = app.grading.testcases.program == keyprog;
          set_tcs = app.grading.testcases(tcs_filter,:);

          % Set student program information
          stu_prog_idx = ...
            app.grading.stu_progs.matched_key_name == ...
            keyprog & ...
            app.grading.stu_progs.last_name == stu_name;
          stu_prog = app.grading.stu_progs(stu_prog_idx, :);

          % Is there a submission for this student
          %                     if height(stu_prog) == 0
          %
          %                         % Skip this student
          %                         continue
          %
          %                     end

          stu_file = stu_prog.submission_name;

          % Set output function info
          output_func_name = [keyprefix '_output_generator'];

          % Get function handle for the output program
          output_func = str2func(output_func_name);

          got_var_err = false;
          try
            stu_var_names = ...
              get_variable_names(app, stu_file, stu_path);
          catch
            got_var_err = true;
            tc_inputs = [];
            stu_outputs = [];
          end

          if got_var_err
            grading_err = struct( ...
              'var_err', got_var_err, ...
              'unresponsive', false);
          else
            % Get the student's output values
            [tc_inputs, stu_outputs, grading_err] = ...
              output_func(stu_file);
          end

          % Get student builtin functions
          stu_bltins = ...
            categorical(rmmissing(stu_prog.builtins));

          % Filter builtins by key program
          bltins_tbl = all_bltins_tbl( ...
            all_bltins_tbl.program == keyprog, ...
            :);

          unauth_fltr = ~( ...
            bltins_tbl.authorized | ...
            bltins_tbl.ignore | ...
            bltins_tbl.standard ...
            );

          % Get the index for matching builtins
          match_idx = ismember(bltins_tbl.name, stu_bltins);

          % Get the deduction for using this builtin
          unauth_bltins = ...
            bltins_tbl.name(unauth_fltr & match_idx);
          unauth_deduct = ...
            bltins_tbl.penalty(unauth_fltr & match_idx);

          % Create set results structure
          stu_results.(keyprog).inputs = tc_inputs;
          stu_results.(keyprog).input_vars = stu_var_names.input;
          stu_results.(keyprog).n_inputs = ...
            length(stu_var_names.input);
          stu_results.(keyprog).outputs = stu_outputs;
          stu_results.(keyprog).output_vars = ...
            stu_var_names.output;
          stu_results.(keyprog).n_outputs = ...
            length(stu_var_names.output);
          stu_results.(keyprog).prog_value = ...
            key_data.(keyprog).pt_val;
          stu_results.(keyprog).prog_score = 0;
          stu_results.(keyprog).pts_per_tc = ...
            key_data.(keyprog).pts_per_tc;
          stu_results.(keyprog).pts_per_output = ...
            key_data.(keyprog).pts_per_output;
          stu_results.(keyprog).manual_grade = ...
            key_data.(keyprog).n_outputs == 0;
          stu_results.(keyprog).grading_err = grading_err;
          stu_results.(keyprog).submitted = ~isempty(stu_file);
          stu_results.(keyprog).unauth_bltins = unauth_bltins;
          stu_results.(keyprog).unauth_deduct = unauth_deduct;

        end

        % Compare the student outputs with the key
        stu_results = assess_outputs(app, key_data, stu_results);

        % Update graded and hw_score field of current student
        stu_results = compute_scores(app, stu_results);

        % Create the student feedback form
        create_feedback_form(app, stu_results)

        % Save the student feedback mat file
        save(hw_stu_file, '-struct', 'stu_results')

        rmpath(stu_path)

      end

      rmpath(genpath(app.dirs.hw))

      close(progressbar);

    end
    %-----------------------------------------------------------------
    function stu_data = assess_outputs(app, key_data, stu_data)

      % Loop through each key program in the current testcases
      for k = 1:height(app.grading.testcases)

        key_prog = char(app.grading.testcases.program(k));

        if ~isfield(stu_data, key_prog)
          continue
        end

        % Get the key and student outputs for this program
        key_outputs = key_data.(key_prog).outputs;
        stu_outputs = stu_data.(key_prog).outputs;

        feedback(1:height(key_outputs),1) = struct();

        if ~isempty(stu_outputs)
          % Loop through each test case for the current program
          for set = 1:height(key_outputs)

            % Loop through each output
            for n = 1:width(key_outputs)

              key = key_outputs{set, n};
              stu = stu_outputs{set, n};

              % Check class
              feedback(set).stu_class{n} = class(stu);
              feedback(set).key_class{n} = class(key);

              % Check for empty
              if isempty(stu) && isempty(key)
                stu = [];
                key = [];
              end

              % Check size
              feedback(set).stu_size{n} = size(stu);
              feedback(set).key_size{n} = size(key);

              % Check value
              feedback(set).stu_value{n} = stu;
              feedback(set).key_value{n} = key;

              % Check for runtime error
              if any(contains( ...
                  fields( ...
                  stu_data.(key_prog).grading_err(set)), ...
                  'matlab_error') ...
                  ) && ...
                  ~isempty(stu_data.(key_prog). ...
                  grading_err(set). ...
                  matlab_error ...
                  )

                feedback(set).correct(n) = false;

              else

                % Check equality with key
                feedback(set).correct(n) = ...
                  isequal(key, stu);
              end

            end

          end
        end

        stu_data.(key_prog).feedback = feedback;

        clear feedback

      end


    end
    %-----------------------------------------------------------------

    function stu_data = compute_scores(app, stu_data)

      hw_score = 0;

      % Scan through selected programs
      for k = 1:height(app.grading.sel_keys)

        % Get current program as a char array
        p_name = char(app.grading.sel_keys.program(k));

        all_fb = stu_data.(p_name).feedback;

        % If this is a manually graded program, skip it
        if stu_data.(p_name).manual_grade
          continue
        end

        pts_per_output = stu_data.(p_name).pts_per_output;
        stu_unresp = repmat( ...
          [stu_data.(p_name).grading_err.unresponsive], ...
          stu_data.(p_name).n_outputs, 1 ...
          );

        if isfield( stu_data.(p_name).feedback,'correct') && ...
            stu_data.(p_name).submitted

          prob_score = sum(pts_per_output * ...
            ~stu_unresp(:)' ...
            .* [stu_data.(p_name).feedback.correct]);
        else
          prob_score = 0;
        end

        %                 % Scan through the feedback for each test case
        %                 for j = 1:length(all_fb)
        %
        %                     prob_score = prob_score + ...
        %                         sum(pts_per_output*all_fb(j).correct);
        %
        %                 end

        % Subtract any deductions
        if prob_score > 0
          prob_score = prob_score - ...
            sum(stu_data.(p_name).unauth_deduct);
        end

        stu_data.(p_name).prog_score = max(0, prob_score);

        hw_score = hw_score + prob_score;

      end

      stu_data.hw_score = hw_score;

      stu_data.graded = true;

    end
    %-----------------------------------------------------------------
    function create_feedback_form(app, stu_fb)

      hw_num = char(app.home.hw);

      % Correct and incorrect symbols
      correct = char(10004);
      incorrect = char(10006);

      % Script Title
      file_text = "%% Homework " + hw_num(4:5) + " Results";

      file_text = [file_text; ...
        "%"; ...
        "% Name: " + stu_fb.last_name; ...
        ];

      % Common text display lines
      single_hline = string(['%  ' repmat('-',1,100)]);
      double_hline = string(['%  ' repmat('=',1,100)]);

      % Program Loop
      for k = 1:length(app.grading.sel_keys.program)

        key_name = char(app.grading.sel_keys.program(k));

        file_text = [file_text; ...
          "%"; ...
          "%% Program: " + key_name; ...
          "%"; ...
          %                     "%  Test Cases: "; ...
          single_hline; ...
          ];

        stu_prog = stu_fb.(key_name);
        stu_prog.comments = [];

        % Test case Loop
        for j = 1:height(stu_fb.(key_name).inputs)

          if ~stu_prog.submitted

            file_text = [...
              file_text; ...
              "%  Program not submitted or mis-titled." ...
              ];
            break

          end

          if stu_prog.manual_grade

            file_text = [file_text; "%  Manually Graded"];
            break

          end

          fb_results = stu_prog.feedback(j);

          input_str = mat2str(stu_fb.(key_name).inputs{j,1});

          if length(stu_fb.(key_name).inputs(j,:)) == ...
              stu_prog.n_inputs

            for i = 2:stu_prog.n_inputs

              input_str = [input_str ', ' ...
                mat2str(stu_fb.(key_name).inputs{j,i})];

            end

          end

          file_text = [file_text; "%  Inputs: " + input_str];

          if stu_prog.n_outputs == 0

            file_text = [file_text; "%  Outputs: None"];

          else

            file_text = [file_text; "%  Outputs: "];

          end

          % Check for too many output variables
          if stu_prog.n_outputs > length(fb_results.correct)

            stu_prog.comments = [stu_prog.comments; ...
              "Too many output variables"];

            break

          end

          for i = 1:stu_prog.n_outputs

            % Check for errors
            if any(contains( ...
                fields( ...
                stu_prog.grading_err(j)), ...
                'matlab_error') ...
                ) && ...
                ~isempty( ...
                stu_prog.grading_err(j).matlab_error ...
                )

              err_msg = string(linewrap(app, ...
                stu_prog.grading_err(j) ...
                .matlab_error ...
                .message));

              indent(1:length(err_msg),1) = "%       ";

              err_msg = indent + err_msg;

              file_text = [file_text; ...
                "%    " + incorrect + " " + ...
                "This test case returned the " + ...
                "following error: "; ...
                err_msg; ...
                ];

              continue

            end

            % Check for unresponsive code
            if  stu_prog.grading_err(j).unresponsive

              stu_prog.comments = ...
                "Check your code for an infinite loop.";

              file_text = [file_text; ...
                "%    " + incorrect + " " + ...
                "Program was unresponsive" ...
                ];

              continue

            elseif fb_results.correct(i)

              fb_sym = correct;

            else

              fb_sym = incorrect;

            end

            file_text = [...
              file_text; ...
              "%    " + fb_sym + " " + ...
              strip(stu_prog.output_vars{i}) + " = " + ...
              mat2str(stu_prog.outputs{j,i}); ...
              ];

            if ~fb_results.correct(i)

              if isempty(fb_results.key_value{i})
                file_text = [...
                  file_text; ...
                  "%        Expected: []"; ...
                  ];
              else
                file_text = [...
                  file_text; ...
                  "%        Expected: " + ...
                  mat2str(fb_results.key_value{i}); ...
                  ];
              end

            end

          end

          if j ~= height(stu_fb.(key_name).inputs)
            file_text = [file_text;single_hline;];
          end

        end

        if stu_prog.submitted

          % Check for errors
          if any(contains(fields( ...
              stu_prog.grading_err(j) ...
              ),'matlab_error'))

            file_text = [file_text; ...
              double_hline; ...
              "%  Comments: "; ...
              "%  * Program could not be run " + ...
              "due to error."; ...
              single_hline; ...
              ];


          elseif ~isempty(stu_prog.comments)

            file_text = [file_text; ...
              double_hline; ...
              "%  Comments: "; ...
              "%  * " + stu_prog.comments; ...
              single_hline; ...
              ];

          else

            file_text = [file_text; ...
              double_hline; ...
              "%  Comments: None"; ...
              single_hline; ...
              ];

          end

          if ~isempty(stu_prog.unauth_bltins)

            file_text = [file_text; ...
              "%  Deductions: "; ...
              "%  Unauthorized builtin functions:"; ...
              "%   * " + string(stu_prog.unauth_bltins) + ...
              ": -" + string(stu_prog.unauth_deduct); ...
              single_hline; ...
              ];

          else

            file_text = [file_text; ...
              "%  Deductions: None"; ...
              single_hline; ...
              ];

          end

          file_text = [file_text; ...
            "%  Points: " + round(stu_prog.prog_score,2) + ...
            "/" + stu_prog.prog_value; ...
            single_hline; ...
            "%  Eligible for resubmission: Yes"; ...
            double_hline; ...
            ];

        else

          file_text = [file_text; ...
            double_hline; ...
            "%  Points: " + round(stu_prog.prog_score,2) + ...
            "/" + stu_prog.prog_value; ...
            single_hline; ...
            "%  Eligible for resubmission: No"; ...
            double_hline; ...
            ];

        end

      end

      file_text = [file_text; ...
        "%"; ...
        "%% HW Score: " + round(stu_fb.hw_score,2) + ...
        "/" + stu_fb.hw_value; ...
        ];

      % Write the get output function
      fb_file = string(stu_fb.hw) + ...
        "_results_" + stu_fb.last_name + ".m";
      fb_html = string(stu_fb.hw) + ...
        "_results_" + stu_fb.last_name + ".html";

      hw_result_dir = ...
        [app.dirs.hw '\student_results\' app.home.subtype];

      if ~exist(hw_result_dir, 'dir')

        mkdir(hw_result_dir)

      end

      writelines(file_text, hw_result_dir + "\" + fb_file);

      pub_opts.format = 'html';
      pub_opts.showCode = false;
      pub_opts.evalCode = false;

      publish(hw_result_dir + "\" + fb_file, pub_opts);

    end

    %-----------------------------------------------------------------
    function hw_key_data = ...
        update_key_testcase_output(app, modified_only)

      % Load key output mat file
      mat_name = [ ...
        app.dirs.hw '\' ...
        char(app.home.hw) '_key_outputs_' ...
        app.home.subtype ...
        '.mat' ...
        ];

      % If a HW key output mat does not exist, create one
      if ~exist(mat_name,'file')

        msgbox(['The hw key mat file does not exist. ' ...
          'What have you done????'])

      end

      if app.home.resub

        msgbox(['Resubmission mat file not implemented. Using ' ...
          'original testcases. Needs to be fixed.'])

        % Original testcase mat file name
        temp = [ ...
          app.dirs.hw '\' ...
          char(app.home.hw) '_key_outputs_original.mat' ...
          ];

        % load the HW key output mat
        hw_key_data = load(temp);

      else

        % load the HW key output mat
        hw_key_data = load(mat_name);

      end

      % Get modified testcases for this course and hw
      course_fltr = app.testcases.course == app.home.course;
      hw_fltr = app.testcases.hw == app.home.hw;

      if modified_only
        mod_fltr = app.testcases.modified & course_fltr & hw_fltr;
      else
        mod_fltr = course_fltr & hw_fltr;
      end

      modified_tcs = app.testcases(mod_fltr,:);

      hw_pts_possible = 0;

      % Don't do anything if there are no modified test cases
      if height(modified_tcs) == 0

        return

      end

      % Scan through each program that needs to be updated
      for j = 1:height(app.grading.sel_keys)

        prog = char(app.grading.sel_keys.program(j));

        % Set key program information
        kd.name = prog;
        kd.row = ...
          app.keyprograms(app.keyprograms.program == prog,:);
        kd.file = kd.row.m_name;
        kd.prefix = char(kd.row.prefix);
        kd.prog_path = [app.dirs.hw '\' prog];
        kd.n_inputs = kd.row.n_inputs;
        kd.n_outputs = kd.row.n_outputs;

        % filter the testcases for this program
        prog_fltr = modified_tcs.program == prog;

        kd.tcs = modified_tcs(prog_fltr,:);

        % Number of testcases
        n_tcs = height(kd.tcs);

        if kd.tcs.input(1) ~= "" && kd.tcs.value(1) == ""

          % Skip this program
          continue

        end

        % Add key directory to the MATLAB path
        addpath(kd.prog_path)

        % Set output function info
        output_func_name = [kd.prefix '_output_generator'];

        % Create the output generator
        create_output_generator(app, kd)

        % Get function handle for the output program
        output_func = str2func(output_func_name);

        % Get the new outputs
        [tc_inputs, key_outputs, msg] = output_func(kd.name);

        prog_pts = kd.row.pt_value;
        pts_per_tc = prog_pts/n_tcs;
        pts_per_output = pts_per_tc/kd.n_outputs;

        hw_pts_possible = hw_pts_possible + prog_pts;

        % Create set results structure
        hw_key_data.(prog).n_inputs = kd.n_inputs;
        hw_key_data.(prog).inputs = tc_inputs;
        hw_key_data.(prog).n_outputs = kd.n_outputs;
        hw_key_data.(prog).outputs = key_outputs;
        hw_key_data.(prog).pt_val = prog_pts;
        hw_key_data.(prog).n_tcs = n_tcs;
        hw_key_data.(prog).pts_per_tc = pts_per_tc;
        hw_key_data.(prog).pts_per_output = pts_per_output;
        hw_key_data.(prog).key_err = msg;

        % Remove key directory to the MATLAB path
        rmpath(kd.prog_path)

      end

      hw_key_data.hw_value = hw_pts_possible;
      save(mat_name, '-struct', 'hw_key_data')

      % Reset modified field
      app.testcases.modified(mod_fltr) = false;

      data_save_datatable(app, 'testcases')

    end

  end

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)

      % Clean up workspace
      evalin('base', 'clear unresponsive')
      evalin('base', 'close all')

      % Define the app as a global variable
      % This is only used to detect infinite loops in programs
      assignin('base','unresponsive', app);

      % Set initialization flag to true
      app.initializing = true;

      % Set application directory
      app.dirs.root = fileparts(mfilename('fullpath'));
      cd(app.dirs.root);

      % Set kernel directory
      app.dirs.kernel = [app.dirs.root,'\kernel'];

      % Set icons directory
      app.dirs.icons = [app.dirs.kernel '\app_icons'];

      % Set user directory
      if ispc
        app.dirs.user = winqueryreg('HKEY_CURRENT_USER',...
          ['Software\Microsoft\Windows\CurrentVersion\' ...
          'Explorer\Shell Folders'],'Personal');
        app.dirs.user = replace(app.dirs.user,'Documents','');
      else
        app.dirs.user = ...
          char(java.lang.System.getProperty('user.home'));
      end

      % Set data folder
      app.dirs.data = [app.dirs.root '\data'];

      % Create a data folder if it doesn't exist
      if ~exist(app.dirs.data,'dir')

        mkdir(app.dirs.data)

      end

      % General settings file name
      settings_file = [app.dirs.data '\settings.mat'];

      % Does the setting file NOT exist?
      if ~exist(settings_file,'file')

        % Create the settings file with defaults and save it
        app.settings.last_course = categorical("");
        app.settings.last_hw = categorical("");
        app.settings.last_sub_type = 'original';
        data_save_mat_file(app, 'settings')

      else

        % load it
        app.settings = open(settings_file);

      end

      % Loads the database
      initialize_app_data(app)

      % Loads various styles used in the app
      initialize_styles(app)

      % Are there any courses yet?
      if height(app.courses) > 0

        clear_home_tab_info(app)

        if any(app.courses.name == app.settings.last_course) && ...
            exist( ...
              [app.dirs.root '\' char(app.settings.last_course)], ...
              'file')

          % Set the initially selected course and hw (perhaps empty)
          app.home.course = app.settings.last_course;
          app.home.hw = app.settings.last_hw;

        else

          % Set the initially selected course to the first course 
          % and hw (perhaps empty)
          app.home.course = app.courses.name(1);
          possible_hws = ...
            app.homeworks.name(app.homeworks.course == app.home.course);
          app.home.hw = possible_hws(1);

        end
          
        if strcmp(app.settings.last_sub_type,'original')

          app.home.resub = false;
          app.home.subtype = 'original';

        else

          app.home.resub = true;
          app.home.subtype = 'resubmission';

        end

        load_course(app)

        display_home_page(app)

      end

      % Set initialization flag to false
      app.initializing = false;

    end

    % Selection change function: app_tabs
    function app_tabsSelectionChanged(app, event)

      %             % Was there a change to the testcases
      %             if height(app.tc_keyprog) > 0
      %
      %                 update_hw_key(app)
      %
      %             end

      if isempty(app.home)

        return

      end

      switch app.app_tabs.SelectedTab.Title

        case 'Home'


        case 'Test Cases'

          app.tc_keyprog_row = table();

          % Set Homework Test Case Label
          app.tc_label.Text = [char(app.home.hw) ' Test Cases'];

          % Add horizontal alignment to program value column
          addStyle(app.tc_programs_table, ...
            app.styles.hor_align, "column", 2)

          % Add horizontal alignment to all test case columns
          addStyle(app.tc_cases_table, ...
            app.styles.hor_align,"column", 1:4)

          % Are there any test cases for this hw?
          if height(app.cur_keyprograms) > 0

            first_program = app.cur_keyprograms.program(1);
            data_filter_tcs(app, first_program, 1)

            % Set the selected set program
            display_tc_programs(app, 1)

          else

            % Send the user back to the home tab
            msgbox([ ...
              'Import key program for ' char(app.home.hw) ...
              ' before creating test cases.' ...
              ])
            app.app_tabs.SelectedTab = app.home_tab;

          end

        case 'Grading'

          % Set Course Label (determined by home tab selection)
          app.grading_course_label2.Text = app.home.course;

          % Set HW Label (determined by home tab selection)
          app.grading_hw_label2.Text = app.home.hw;

          % Set roster table (will not change)
          app.grading.roster = app.cur_students(:,"last_name");

          % Populate Student Table (will not change)
          app.grading_student_table.Data = app.grading.roster;

          % Set selected student
          app.grading.sel_rows = 1;
          app.grading.sel_studs = app.grading.roster.last_name(1);

          % Set course/hw program table (will not change)
          stu_filter = app.stuprograms.hw == app.home.hw & ...
            app.stuprograms.course == app.home.course & ...
            app.stuprograms.resubmission == app.home.resub;
          app.grading.program = app.stuprograms(stu_filter,:);

          app.grading.key_progs = app.cur_keyprograms;
          app.grading.sel_key_rows = ...
            1:length(app.cur_keyprograms.program);
          app.grading.sel_keys = app.cur_keyprograms;

          % Set testcases for current course/hw
          tc_filter = app.testcases.hw == app.home.hw & ...
            app.testcases.course == app.home.course;
          app.grading.testcases = app.testcases(tc_filter,:);

          display_grading_page(app)

        case 'Settings'

          std_fltr = ...
            app.builtins.course == app.home.course & ...
            app.builtins.standard;

          app.settings_std_authorized_builtins.Value = ...
            unique(app.builtins.name(std_fltr));

        case 'Grading Summary'

          app.grading_summary_course_label.Text = ...
            char(app.home.course) +  ": " + ...
            char(app.home.hw) + " Summary";

          %                     app.grading_summary_table.Data = app.student_results;

      end

    end

    % Menu selected function: home_open_key
    function home_open_keySelected(app, event)

      key_prog_action_open(app)

    end

    % Menu selected function: home_create_pcode
    function home_create_pcodeSelected(app, event)

      keyaction_create_p_codes(app)

    end

    % Menu selected function: home_add_course
    function home_add_courseSelected(app, event)

      % add new course to database
      create_new_course(app)
      load_course(app)
      display_home_page(app)

    end

    % Selection changed function: home_courses_table
    function home_courses_TableSelectionChanged(app, event)

      % Get the currently selected row
      selection = app.home_courses_table.Selection;

      % Is the new selection empty
      if ~isempty(selection)

        clear_home_tab_info(app)

        % Update the selected course and row
        app.home.course = ...
          app.home_courses_table.Data{selection, 1};

        % Refresh the homepage interface
        load_course(app)

        display_home_page(app)

      end

    end

    % Button down function: home_courses_table
    function home_courses_tableButtonDown(app, event)

      % Has a course been selected?
      if ~isempty(app.home)

        % Set the course name on the delete course context menu
        app.home_del_course.Text = ...
          ['Delete ' char(app.home.course)];


        % Set the course name on the import roster context menu
        app.home_import_roster.Text = ...
          ['Import Roster for ' char(app.home.course)];

      end

    end

    % Menu selected function: home_import_roster
    function home_import_rosterMenuSelected(app, event)

      create_new_roster(app)
      load_course(app)
      display_home_page(app)

    end

    % Menu selected function: home_import_keys
    function home_import_keysMenuSelected(app, event)

      import_key_programs(app)
      create_new_key_programs(app)
      load_course(app)
      display_home_page(app)

    end

    % Menu selected function: home_add_hw
    function home_add_hwMenuSelected(app, event)

      create_new_hw_set(app)
      load_course(app)
      display_home_page(app)

    end

    % Menu selected function: home_import_submissions
    function home_import_submissionsMenuSelected(app, event)

      import_hw_submissions(app)
      load_course(app)

      % Set the first student as the selected student
      app.home.student = app.cur_students.last_name(1);

      display_home_page(app)

    end

    % Selection changed function: home_homeworks_table
    function home_homeworks_tableSelectionChanged(app, event)

      % Get the currently selected row
      selection = app.home_homeworks_table.Selection;

      % Is the new selection empty
      if ~isempty(selection)

        % Update the selected homework
        app.home.hw = categorical( ...
          app.home_homeworks_table.Data{selection, 1});

        % Refresh the homepage interface
        load_course(app)

        display_home_page(app)

      end

    end

    % Selection changed function: home_roster_table
    function home_roster_tableSelectionChanged(app, event)

      % Get the currently selected row
      selection = app.home_roster_table.Selection;

      % Is the new selection empty
      if ~isempty(selection)

        % Update the selected student
        app.home.student = categorical( ...
          app.home_roster_table.Data{selection, 1});

        % Reset the selected student program
        app.home.stuprog = "";
        app.home.stuprog_id = [];

        % Refresh the homepage interface
        load_course(app)

        display_home_page(app)

      end

    end

    % Cell edit callback: home_stuprogs_table
    function home_stuprogs_tableCellEdit(app, event)

      % Was the click valid?
      if isempty(event.Indices)

        % Don't do anything
        return

      end

      row = event.Indices(1,1);
      col = event.Indices(1,2);
      new_data = event.NewData;

      stu_filter = ...
        app.stuprograms.course == app.home.course & ...
        app.stuprograms.hw == app.home.hw & ...
        app.stuprograms.last_name == app.home.student & ...
        app.stuprograms.submission_name == ...
        app.home_stuprogs_table.Data.submission_name(row);

      % Was the submitted program name changed?
      if col == 1

        % Rename the submitted program name

      end

      % Was the matched program changed?
      if col == 2

        % Set the new matched program
        app.stuprograms.matched_key_name(stu_filter) = ...
          categorical(new_data,'Ordinal',false);

      end

      % Was the mis-title column changed?
      if col == 3

        % Set the new matched program
        app.stuprograms.mis_titled(stu_filter) = new_data;

      end

      % Was the late column changed?
      if col == 4

        % Set the new matched program
        app.stuprograms.late(stu_filter) = new_data;

      end

      data_save_datatable(app, 'stuprograms')

      load_course(app)

      display_home_page(app)

    end

    % Menu selected function: home_rename_program
    function home_rename_programMenuSelected(app, event)

      row = app.home_stuprogs_table.Selection(1,1);
      old_name = app.home_stuprogs_table.Data.submission_name(row);

      user_input = inputdlg({'Enter the New Program Title:'}, ...
        'Change Student Program Name', ...
        [1 70], {char(old_name)});

      stu_filter = ...
        app.stuprograms.course == app.home.course & ...
        app.stuprograms.hw == app.home.hw & ...
        app.stuprograms.last_name == app.home.student & ...
        app.stuprograms.submission_name == old_name;

      % Set the new submitted program name
      app.stuprograms.submission_name(stu_filter) = ...
        categorical(user_input, 'Ordinal', false);

      data_save_datatable(app, 'stuprograms')

      load_course(app)

      display_home_page(app)

    end

    % Menu selected function: home_set_to_manual_grade
    function home_set_to_manual_gradeSelected(app, event)

      if strcmp(app.app_tabs.SelectedTab.Title,'Test Cases')

        sel_program = app.tc_keyprog;

      else

        row = app.home_keyprogs_table.Selection(1,1);
        sel_program = app.home_keyprogs_table.Data.program(row);

      end

      key_filter = ...
        app.keyprograms.course == app.home.course & ...
        app.keyprograms.hw == app.home.hw & ...
        app.keyprograms.program == sel_program;

      % Set the new submitted program name
      app.keyprograms.manual_grade(key_filter) = true;

      data_save_datatable(app, 'keyprograms')

    end

    % Menu selected function: home_del_program
    function home_del_programMenuSelected(app, event)

      stu_prog_filter = ismember( ...
        app.stuprograms.stuprogram_id, app.home.stuprog_id ...
        );
      app.stuprograms(stu_prog_filter,:) = [];

      data_save_datatable(app, 'stuprograms')

      load_course(app)

      display_home_page(app)

    end

    % Menu selected function: home_set_to_auto_grade
    function home_set_to_auto_gradeSelected(app, event)

      row = app.home_keyprogs_table.Selection(1,1);
      sel_program = app.home_keyprogs_table.Data.program(row);

      key_filter = ...
        app.keyprograms.course == app.home.course & ...
        app.keyprograms.hw == app.home.hw & ...
        app.keyprograms.program == sel_program;

      % Set the new submitted program name
      app.keyprograms.manual_grade(key_filter) = false;

      data_save_datatable(app, 'keyprograms')

    end

    % Selection changed function: home_submission_buttons
    function home_submission_buttonsSelectionChanged(app, event)

      selection = app.home_submission_buttons.SelectedObject;

      if strcmp(selection.Text, 'Original Submissions')

        app.settings.last_sub_type = 'original';
        app.home.resub = false;
        app.home.subtype = 'original';


      else

        app.settings.last_sub_type = 'resubmission';
        app.home.resub = true;
        app.home.subtype = 'resubmission';

      end

      data_save_mat_file(app, 'settings')
      load_course(app)
      display_home_page(app)

    end

    % Selection changed function: grading_student_table
    function grading_student_tableSelectionChanged(app, event)

      app.grading.sel_rows = app.grading_student_table.Selection;
      app.grading.sel_studs = ...
        app.grading.roster.last_name(app.grading.sel_rows);

      %             % Filter all selected students
      %             stu_filter = ismember( ...
      %                 app.grading.program.last_name,app.grading.sel_studs);
      %
      %             app.grading.stu_progs = app.grading.program(stu_filter,:);

      display_grading_page(app)

    end

    % Callback function: grading_student_select_all
    function grading_student_select_allClicked(app, event)

      app.grading.sel_rows = 1:height(app.grading.roster);
      app.grading.sel_studs = ...
        app.grading.roster.last_name(app.grading.sel_rows);

      display_grading_page(app)

      app.grading_student_table.Selection = app.grading.sel_rows;

    end

    % Selection changed function: grading_program_table
    function grading_program_tableSelectionChanged(app, event)

      app.grading.sel_key_rows = app.grading_program_table.Selection;
      app.grading.sel_keys = ...
        app.grading.key_progs(app.grading.sel_key_rows,:);

      display_grading_page(app)

    end

    % Cell edit callback: grading_builtin_detected_table
    function grading_builtin_detected_tableCellEdit(app, event)
      row = event.Indices(1,1);
      col = event.Indices(1,2);

      prog_name = app.grading_builtin_detected_table.Data.name(row);

      new_val = event.NewData;

      % Was allowed checked?
      if col == 2

        % Filter the builtins data
        fltr = app.builtins.course == app.home.course & ...
          app.builtins.hw == app.home.hw & ...
          ismember(app.builtins.name, prog_name) & ...
          ismember( ...
          app.builtins.program, ...
          app.grading.sel_keys.program ...
          );

        app.builtins.authorized(fltr) = new_val;

        % Was ignore checked?
      elseif col == 3

        % Filter the builtins data
        fltr = app.builtins.course == app.home.course & ...
          app.builtins.hw == app.home.hw & ...
          ismember(app.builtins.name, prog_name) & ...
          ismember( ...
          app.builtins.program, ...
          app.grading.sel_keys.program ...
          );

        app.builtins.ignore(fltr) = new_val;

        % Was standard checked?
      elseif col == 4

        % Filter the builtins data
        fltr = app.builtins.course == app.home.course & ...
          app.builtins.name == prog_name;

        app.builtins.standard(fltr) = new_val;

        % Was penalty changed?
      elseif col == 5

        % Filter the builtins data
        fltr = app.builtins.course == app.home.course & ...
          app.builtins.hw == app.home.hw & ...
          ismember(app.builtins.name, prog_name) & ...
          ismember( ...
          app.builtins.program, ...
          app.grading.sel_keys.program ...
          );

        app.builtins.penalty(fltr) = new_val;

      end

      data_save_datatable(app, 'builtins')

      display_grading_page(app)

    end

    % Callback function: grading_program_select_all
    function grading_program_select_allHyperlinkClicked(app, event)

      app.grading.sel_key_rows = ...
        1:height(app.grading_program_table.Data);
      app.grading.sel_keys = ...
        app.grading.key_progs(app.grading.sel_key_rows,:);

      display_grading_page(app)

      app.grading_program_table.Selection = app.grading.sel_key_rows;

    end

    % Display data changed function: grading_builtin_detected_table
    function grading_builtin_detected_tableDisplayDataChanged(app, event)
      new_data = ...
        app.grading_builtin_detected_table.DisplayData;

      % Filter the builtins data
      fltr = app.builtins.course == app.home.course & ...
        app.builtins.hw == app.home.hw & ...
        ismember(app.builtins.name, new_data.name) & ...
        ismember( ...
        app.builtins.program, ...
        app.grading.sel_keys.program ...
        );

      app.builtins.authorized(fltr) = new_data.authorized;
      app.builtins.ignore(fltr) = new_data.ignore;
      app.builtins.standard(fltr) = new_data.standard;

      std_T_fltr = ismember( ...
        app.builtins.name, new_data.name(new_data.standard) ...
        );

      std_F_fltr = ismember( ...
        app.builtins.name, new_data.name(~new_data.standard) ...
        );

      % Propogate changes to all builtins w/ the same name
      app.builtins.standard(std_T_fltr) = true;
      app.builtins.standard(std_F_fltr) = false;

      data_save_datatable(app, 'builtins')

      display_grading_page(app)

    end

    % Menu selected function: grading_open_selected_stuprogs
    function grading_open_selected_stuprogsSelected(app, event)

      % Get all the paths to open
      prog_paths = ...
        string(app.dirs.students) + "\" + ...
        string(app.grading.stu_progs.last_name) + "\" + ...
        string(app.home.hw) + "\" + ...
        string(app.settings.last_sub_type) + "\" + ...
        string(app.grading.stu_progs.submission_name) + "." + ...
        string(app.grading.stu_progs.file_ext);

      % Open each program with the edit command
      cellfun(@edit, prog_paths)

    end

    % Menu selected function: grading_add_builtin_to_std_list
    function grading_add_builtin_to_std_listSelected(app, event)
      help=piss

    end

    % Menu selected function: grading_grade_selected_stuprogs
    function grading_grade_selected_stuprogsSelected(app, event)
      grade_stu_programs(app)
    end

    % Selection changed function: tc_programs_table
    function tc_programs_tableSelectionChanged(app, event)

      % Get and set the selected program
      prog_select = app.tc_programs_table.Selection;
      app.tc_keyprog = app.tc_programs_table.Data{prog_select,1};

      % Filter and Refresh the interface
      data_filter_tcs(app, app.tc_keyprog, 1)
      display_tc_programs(app, prog_select)

      % Remove the selection visual
      app.tc_programs_table.Selection = [];

    end

    % Selection changed function: tc_cases_table
    function tc_cases_tableSelectionChanged(app, event)

      % Get and set the selected test case set
      set_select = app.tc_cases_table.Selection;
      app.tc_set = app.tc_cases_table.Data{set_select(1), 1};

      if set_select(1) == height(app.tc_cases_table.Data)

        % Was the first column of the last row selected?
        if set_select(2) == 1

          % Add button was selected
          create_new_single_test_case(app);

        else

          % Invalid selection
          return

        end

      end

      % Filter and Refresh the interface
      data_filter_tcs(app, app.tc_keyprog, app.tc_set)
      display_tc_sets(app, set_select(1))
      display_tc_commands(app)

      % Remove user selection visual
      app.tc_cases_table.Selection = [];

    end

    % Cell edit callback: tc_programs_table
    function tc_programs_tableCellEdit(app, event)

      indices = event.Indices;

      % Get the program that changed
      changed_program = ...
        app.tc_programs_table.Data.program(indices(1));

      % Get the testcase_id for this program
      tc_id_filter = app.cur_tc_hw.program == changed_program;
      tc_id = app.cur_tc_hw(tc_id_filter,:).testcase_id;

      % Get the data that changed
      new_data = event.NewData;

      % Update the keyprograms data
      key_filter = app.keyprograms.program == app.tc_keyprog;
      app.keyprograms.pt_value(key_filter) = str2double(new_data);
      data_save_datatable(app, 'keyprograms');

      % Re-Filter keyprograms
      app.cur_keyprograms = app.keyprograms( ...
        app.keyprograms.course == app.home.course & ...
        app.keyprograms.hw == app.home.hw, :);

      % Update the testcases data
      new_set_val = ...
        str2double(new_data)/(height(app.tc_cases_table.Data)-1);
      app.testcases{tc_id, "set_value"} = new_set_val;
      data_save_datatable(app, 'testcases');

      % Re-populate the set and input subtables
      data_filter_tcs(app, app.tc_keyprog, app.tc_set)
      display_tc_sets(app, app.tc_set)

      app.tc_programs_table.Selection = [];

    end

    % Cell edit callback: tc_cases_table
    function tc_cases_tableCellEdit(app, event)

      indices = event.Indices;

      % Get the case that changed
      changed_case = app.tc_cases_table.Data.set(indices(1));

      % Get the testcase_id for this set and program
      set_filter = app.cur_tc_hw.set == changed_case;
      prog_filter = app.cur_tc_hw.program == app.tc_keyprog;
      tc_id = app.cur_tc_hw( ...
        set_filter & prog_filter,:).testcase_id;

      idx_filter = app.testcases.testcase_id == tc_id;

      % Get the changed data
      new_data = event.NewData;

      new_row = app.testcases(idx_filter, :);

      % Get the column that changed
      if indices(2) == 2

        new_row.set_value = new_data;

        % Update the keyprograms data
        key_id = app.tc_keyprog_row.keyprogram_id;
        key_filter = app.keyprograms.keyprogram_id == key_id;
        app.keyprograms.pt_value(key_filter) = ...
          sum(double(app.tc_cases_table.Data{1:end-1,2}));
        data_save_datatable(app, 'testcases');

      else

        new_row.tolerance = new_data;

      end

      % Update the testcases data
      app.testcases(idx_filter, :) = new_row;
      data_save_datatable(app, 'testcases');

      % Re-populate all subtables
      data_filter_tcs(app, app.tc_keyprog, app.tc_set)

      % Get the row number of the selected program
      prog_row = ...
        find(app.tc_programs_table.Data{:,1} == app.tc_keyprog);

      % Refresh all testcase tables
      display_tc_programs(app, prog_row)

      %             app.tc_inputs_table.Data = ...
      %                 rmmissing([app.cur_tc_set.input' app.cur_tc_set.value']);

      %             % Display the inputs table
      %             app.tc_inputs_table.Data = ...
      %                 app.cur_tc_set(:,["input_name", "input_value"]);

      app.tc_cases_table.Selection = [];

    end

    % Cell edit callback: tc_inputs_table
    function tc_inputs_tableCellEdit(app, event)

      indices = event.Indices;

      % Initialize the new input row
      new_input_row = app.cur_tc_set;

      % Get the changed input info
      input_num = indices(1);
      new_input_row.value(input_num) = event.NewData;

      % Get the testcase_id for this set and program
      tc_id = new_input_row.testcase_id;

      % Update keyprogram script text

      % Update the testcases data
      tc_filter = app.testcases.testcase_id == tc_id;
      app.testcases(tc_filter, :) = new_input_row;
      data_save_datatable(app, 'testcases');

      % Re-populate all subtables
      data_filter_tcs(app, app.tc_keyprog, app.tc_set)

      display_tc_script(app)

      display_tc_commands(app)

      app.tc_inputs_table.Selection = [];

    end

    % Value changed function: tc_script_area
    function tc_script_areaValueChanged(app, event)

      %             % Did the current test cases change?
      %             if ~isempty(app.tc_keyprog)
      %
      %                 msgbox("I don't think the code should go here. Check it.")
      %
      %                 % Logical Index of duplicates
      %                 duplicates = app.tc_keyprog.keyprogram_id == ...
      %                         app.tc_keyprog.keyprogram_id;
      %
      %                 % Is this program already in the queue?
      %                 if any(duplicates)
      %
      %                     % Overwrite it
      %                     app.tc_keyprog(duplicates,:) = app.tc_keyprog;
      %
      %                 else
      %
      %                     % Concatenate it
      %                     app.tc_keyprog = [app.tc_keyprog ; app.tc_keyprog];
      %
      %                 end
      %
      %             else
      %
      %                 app.tc_keyprog = app.tc_keyprog;
      %
      %             end

      save_script_area_data(app)

      data_filter_tcs(app, app.tc_keyprog, app.tc_set)
      display_tc_sets(app, app.tc_set)

    end

    % Value changed function: tc_commands_area
    function tc_commands_areaValueChanged(app, event)

      % Get the header command value
      value = app.tc_commands_area.Value;

      % Update the local header
      app.cur_tc_set.header = value;

      % Get the full logical array of only cur_testcase_ids
      common_ids = ismember(app.testcases.testcase_id, ...
        app.cur_tc_set.testcase_id);

      % Update the global header
      app.testcases(common_ids,:) = app.cur_tc_set;

      % Save the data table
      data_save_datatable(app, 'testcases')

    end

    % Menu selected function: tc_remove_set
    function tc_remove_setSelected(app, event)

      tc_menu_delete(app)

    end

    % Value changed function: settings_std_authorized_builtins
    function settings_std_authorized_builtinsValueChanged(app, event)

      builtin_list = categorical(app.settings_std_authorized_builtins.Value);

      T_fltr = app.builtins.course == app.home.course & ...
        ismember(app.builtins.name, builtin_list);

      F_fltr = app.builtins.course == app.home.course & ...
        ~ismember(app.builtins.name, builtin_list);

      app.builtins.standard(T_fltr) = true;
      app.builtins.standard(F_fltr) = false;

      data_save_datatable(app, 'builtins')

    end

    % Callback function
    function PrintButtonPushed(app, event)

      % STATUS: Not Tested

      studentSelected = app.RosterListBox.Value;
      split_name_at_commas = strsplit(studentSelected,',');
      last_name = split_name_at_commas{1};
      filesSelected = app.ProgramsSubmittedListBox.Value;
      numberOfFilesSelected = length(filesSelected);
      currentFilePath = [app.Grading_Dir,'\Submissions\',last_name,'\',filesSelected{1}];
      wordObj = actxserver('Word.Application');
      wordObj.Documents.Open(currentFilePath);
      wordObj.Visible = 1;
      % print the first file separately to speed up printing multiple files
      print_m_file(app,currentFilePath,wordObj);
      % now print the rest
      for i=2:numberOfFilesSelected
        currentFilePath = [app.Grading_Dir,'\',last_name,'\',filesSelected{i}];
        print_m_file(app,currentFilePath,wordObj);
      end

    end

    % Callback function
    function AddSeedButtonPushed(app, event)
      studentSelected = app.RosterListBox.Value{1};
      split_name_at_commas = strsplit(studentSelected,',');
      last_name = split_name_at_commas{1};
      filesSelected = app.ProgramsSubmittedListBox.Value;
      numberOfFilesSelected = length(filesSelected);
      for i=1:numberOfFilesSelected
        currentFilePath = [app.Submission_Dir,'\',last_name,'\',filesSelected{i}];

        fid = fopen( currentFilePath );
        cac = textscan( fid, '%s', 'Delimiter','\n', 'CollectOutput',true );
        fclose( fid );
        fid = fopen( currentFilePath, 'w' );
        for jj = 1 : length(cac{1})
          if contains(cac{1}{jj},'rand(') || contains(cac{1}{jj},'randi(')
            fprintf(fid,['rng(',num2str(app.EditField.Value),'); \n']);
          end
          fprintf( fid, '%s\n', cac{1}{jj} );
        end
        fclose( fid );
      end

    end

    % Callback function
    function RemoveCommentsButtonPushed(app, event)
      remove_comments(app);
    end

    % Close request function: UIFigure
    function UIFigureCloseRequest(app, event)
      % Suppress warnings for any subfolders not in the current
      % MATLAB Path
      warning('off', 'MATLAB:rmpath:DirNotFound');
      %             rmpath(genpath(app.App_Path));
      delete(app)
      evalin('base', 'clear unresponsive')
      warning('on', 'MATLAB:rmpath:DirNotFound');
    end

    % Menu selected function: home_del_hw
    function home_del_hwMenuSelected(app, event)
      msgbox('Add this later')
    end

    % Button pushed function: tc_refresh_all_tcs
    function tc_refresh_all_tcsButtonPushed(app, event)
      update_key_testcase_output(app, false);
    end

    % Selection changed function: home_keyprogs_table
    function home_keyprogs_tableSelectionChanged(app, event)
      
      % Get the currently selected row
      selection = app.home_keyprogs_table.Selection;

      % Is the new selection empty
      if ~isempty(selection)

        % Update the selected key program
        app.home.keyprog = categorical( ...
          app.home_keyprogs_table.Data{selection, 1});

%         % Refresh the homepage interface
%         load_course(app)
% 
%         display_home_page(app)

      end

    end

    % Selection changed function: home_stuprogs_table
    function home_stuprogs_tableSelectionChanged(app, event)
      % Get the currently selected row
      selection = app.home_stuprogs_table.Selection;

      % Is the new selection empty
      if ~isempty(selection)

        % Update the selected student program
        app.home.stuprog = ...
          app.home_stuprogs_table.Data{selection, 1};
        app.home.stuprog_id = ...
          app.cur_stuprograms.stuprogram_id(selection);

        % Refresh the homepage interface
        load_course(app)

        display_home_page(app)

      end

    end

    % Button pushed function: grading_summary_generate_button
    function grading_summary_generate_buttonButtonPushed(app, event)

      results_table = app.students(app.students.course == app.home.course, "last_name");
      results_table.score = string(zeros(height(results_table), 1));
      results_table.out_of = string(zeros(height(results_table), 1));

      for k = 1:height(results_table.last_name)
        name = char(results_table.last_name(k));
        result_mat = [ ...
          app.dirs.students '\' name '\' ...
          char(app.home.hw) '\' app.home.subtype '\' ...
          char(app.home.hw) '_' name '_results_' ...
          app.home.subtype '.mat'];

        stu_results = load(result_mat);

        results_table.score(results_table.last_name == name) = ...
          sprintf('%.2f', stu_results.hw_score);

      end

      results_table.out_of = ...
        stu_results.hw_value * ones(height(results_table),1);
      app.grading_summary_table.Data = results_table;
      
    end

    % Menu selected function: home_open_stu
    function home_open_stuSelected(app, event)
      
      stu_prog_action_open(app)

    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'UI Figure';
      app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
      app.main_app_label = uilabel(app.UIFigure);
      app.main_app_label.Text = 'MATLAB Grader';
      app.app_tabs = uitabgroup(app.UIFigure);
      app.app_tabs.SelectionChangedFcn = createCallbackFcn(app, @app_tabsSelectionChanged, true);
      app.home_tab = uitab(app.app_tabs);
      app.home_tab.Title = 'Home';
      app.home_grid = uigridlayout(app.home_tab);
      app.home_stuprogs_table = uitable(app.home_grid);
      app.home_stuprogs_table.ColumnName = {'Submitted Programs'; 'Matched'; 'Mistitled'; 'Late'};
      app.home_stuprogs_table.RowName = {};
      app.home_stuprogs_table.SelectionType = 'row';
      app.home_stuprogs_table.ColumnEditable = [false true true true];
      app.home_stuprogs_table.CellEditCallback = createCallbackFcn(app, @home_stuprogs_tableCellEdit, true);
      app.home_stuprogs_table.SelectionChangedFcn = createCallbackFcn(app, @home_stuprogs_tableSelectionChanged, true);
      app.home_keyprogs_table = uitable(app.home_grid);
      app.home_keyprogs_table.ColumnName = {'Assigned Programs'};
      app.home_keyprogs_table.RowName = {};
      app.home_keyprogs_table.SelectionType = 'row';
      app.home_keyprogs_table.SelectionChangedFcn = createCallbackFcn(app, @home_keyprogs_tableSelectionChanged, true);
      app.home_roster_table = uitable(app.home_grid);
      app.home_roster_table.ColumnName = {'Roster'};
      app.home_roster_table.RowName = {};
      app.home_roster_table.SelectionType = 'row';
      app.home_roster_table.SelectionChangedFcn = createCallbackFcn(app, @home_roster_tableSelectionChanged, true);
      app.home_homeworks_table = uitable(app.home_grid);
      app.home_homeworks_table.ColumnName = {'Homeworks'};
      app.home_homeworks_table.RowName = {};
      app.home_homeworks_table.SelectionType = 'row';
      app.home_homeworks_table.SelectionChangedFcn = createCallbackFcn(app, @home_homeworks_tableSelectionChanged, true);
      app.home_courses_table = uitable(app.home_grid);
      app.home_courses_table.ColumnName = {'Courses'};
      app.home_courses_table.RowName = {};
      app.home_courses_table.SelectionType = 'row';
      app.home_courses_table.SelectionChangedFcn = createCallbackFcn(app, @home_courses_TableSelectionChanged, true);
      app.home_courses_table.ButtonDownFcn = createCallbackFcn(app, @home_courses_tableButtonDown, true);
      app.home_submission_buttons = uibuttongroup(app.home_grid);
      app.home_submission_buttons.SelectionChangedFcn = createCallbackFcn(app, @home_submission_buttonsSelectionChanged, true);
      app.home_sub_resub_button = uiradiobutton(app.home_submission_buttons);
      app.home_sub_resub_button.Text = 'Resubmissions';
      app.home_sub_original_button = uiradiobutton(app.home_submission_buttons);
      app.home_sub_original_button.Text = 'Original Submissions';
      app.home_sub_original_button.Value = true;
      app.grading_tab = uitab(app.app_tabs);
      app.grading_tab.Title = 'Grading';
      app.grading_grid = uigridlayout(app.grading_tab);
      app.grading_student_table = uitable(app.grading_grid);
      app.grading_student_table.ColumnName = {'Student'};
      app.grading_student_table.RowName = {};
      app.grading_student_table.SelectionType = 'row';
      app.grading_student_table.SelectionChangedFcn = createCallbackFcn(app, @grading_student_tableSelectionChanged, true);
      app.grading_program_table = uitable(app.grading_grid);
      app.grading_program_table.ColumnName = {'Program'};
      app.grading_program_table.RowName = {};
      app.grading_program_table.SelectionType = 'row';
      app.grading_program_table.SelectionChangedFcn = createCallbackFcn(app, @grading_program_tableSelectionChanged, true);
      app.grading_hw_label2 = uilabel(app.grading_grid);
      app.grading_hw_label2.Text = '';
      app.grading_hw_label1 = uilabel(app.grading_grid);
      app.grading_hw_label1.Text = 'HW Set:';
      app.grading_course_label2 = uilabel(app.grading_grid);
      app.grading_course_label2.Text = '';
      app.grading_course_label1 = uilabel(app.grading_grid);
      app.grading_course_label1.Text = 'Course:';
      app.grading_student_select_all = uihyperlink(app.grading_grid);
      app.grading_student_select_all.HyperlinkClickedFcn = createCallbackFcn(app, @grading_student_select_allClicked, true);
      app.grading_student_select_all.Text = 'Select All';
      app.grading_builtin_detected_table = uitable(app.grading_grid);
      app.grading_builtin_detected_table.ColumnName = {'Unauthorized Built-ins detected'; 'allowed'; 'ignore'; 'standard'; 'penalty'};
      app.grading_builtin_detected_table.RowName = {};
      app.grading_builtin_detected_table.ColumnEditable = true;
      app.grading_builtin_detected_table.CellEditCallback = createCallbackFcn(app, @grading_builtin_detected_tableCellEdit, true);
      app.grading_builtin_detected_table.DisplayDataChangedFcn = createCallbackFcn(app, @grading_builtin_detected_tableDisplayDataChanged, true);
      app.grading_program_select_all = uihyperlink(app.grading_grid);
      app.grading_program_select_all.HyperlinkClickedFcn = createCallbackFcn(app, @grading_program_select_allHyperlinkClicked, true);
      app.grading_program_select_all.Text = 'Select All';
      app.testcases_tab = uitab(app.app_tabs);
      app.testcases_tab.Title = 'Test Cases';
      app.tc_programs_table = uitable(app.testcases_tab);
      app.tc_programs_table.ColumnName = {'Program'; 'Value'};
      app.tc_programs_table.RowName = {};
      app.tc_programs_table.SelectionType = 'row';
      app.tc_programs_table.ColumnEditable = [false true];
      app.tc_programs_table.CellEditCallback = createCallbackFcn(app, @tc_programs_tableCellEdit, true);
      app.tc_programs_table.SelectionChangedFcn = createCallbackFcn(app, @tc_programs_tableSelectionChanged, true);
      app.tc_cases_table = uitable(app.testcases_tab);
      app.tc_cases_table.ColumnName = {''; 'Value'; 'Tolerance'};
      app.tc_cases_table.RowName = {};
      app.tc_cases_table.ColumnEditable = [false true false true];
      app.tc_cases_table.CellEditCallback = createCallbackFcn(app, @tc_cases_tableCellEdit, true);
      app.tc_cases_table.SelectionChangedFcn = createCallbackFcn(app, @tc_cases_tableSelectionChanged, true);
      app.tc_script_area = uitextarea(app.testcases_tab);
      app.tc_script_area.ValueChangedFcn = createCallbackFcn(app, @tc_script_areaValueChanged, true);
      app.tc_script_area.Tooltip = {'Enter Test Cases on individual lines below "%Test Cases".'; ''; 'Separate multiple input values with commas.'; ''; 'Code comments will not be saved.'};
      app.tc_label = uilabel(app.testcases_tab);
      app.tc_label.Text = '[HWNAME] Test Cases';
      app.tc_inputs_table = uitable(app.testcases_tab);
      app.tc_inputs_table.ColumnName = {'Input Name'; 'Input Value'};
      app.tc_inputs_table.RowName = {};
      app.tc_inputs_table.SelectionType = 'row';
      app.tc_inputs_table.ColumnEditable = [false true];
      app.tc_inputs_table.CellEditCallback = createCallbackFcn(app, @tc_inputs_tableCellEdit, true);
      app.TestCaseHeaderCommandsLabel = uilabel(app.testcases_tab);
      app.TestCaseHeaderCommandsLabel.Text = 'Test Case Header Commands';
      app.tc_commands_area = uitextarea(app.testcases_tab);
      app.tc_commands_area.ValueChangedFcn = createCallbackFcn(app, @tc_commands_areaValueChanged, true);
      app.tc_refresh_all_tcs = uibutton(app.testcases_tab, 'push');
      app.tc_refresh_all_tcs.ButtonPushedFcn = createCallbackFcn(app, @tc_refresh_all_tcsButtonPushed, true);
      app.tc_refresh_all_tcs.Tooltip = {'If you changed any files outside the app you may want to refresh the test cases.'};
      app.tc_refresh_all_tcs.Text = 'Refresh All';
      app.GradingSummaryTab = uitab(app.app_tabs);
      app.GradingSummaryTab.Title = 'Grading Summary';
      app.GridLayout4 = uigridlayout(app.GradingSummaryTab);
      app.grading_summary_table = uitable(app.GridLayout4);
      app.grading_summary_table.ColumnName = {'Name'; 'HW Score'; 'Out of'};
      app.grading_summary_table.RowName = {};
      app.grading_summary_course_label = uilabel(app.GridLayout4);
      app.grading_summary_course_label.Text = 'Course Label';
      app.grading_summary_generate_button = uibutton(app.GridLayout4, 'push');
      app.grading_summary_generate_button.ButtonPushedFcn = createCallbackFcn(app, @grading_summary_generate_buttonButtonPushed, true);
      app.grading_summary_generate_button.Text = 'Generate Summary';
      app.settings_tab = uitab(app.app_tabs);
      app.settings_tab.Title = 'Settings';
      app.GridLayout3 = uigridlayout(app.settings_tab);
      app.StandardAuthorizedBuiltinFunctionsTextAreaLabel = uilabel(app.GridLayout3);
      app.StandardAuthorizedBuiltinFunctionsTextAreaLabel.Text = 'Standard Authorized Builtin Functions';
      app.settings_std_authorized_builtins = uitextarea(app.GridLayout3);
      app.settings_std_authorized_builtins.ValueChangedFcn = createCallbackFcn(app, @settings_std_authorized_builtinsValueChanged, true);
      app.TodosTab = uitab(app.app_tabs);
      app.TodosTab.Title = 'To-dos';
      app.BugsFixesandFeatureToDosTextAreaLabel = uilabel(app.TodosTab);
      app.BugsFixesandFeatureToDosTextAreaLabel.Text = 'Bugs Fixes and Feature To-Dos';
      app.BugsFixesandFeatureToDosTextArea = uitextarea(app.TodosTab);
      app.BugsFixesandFeatureToDosTextArea.Editable = 'off';
      app.BugsFixesandFeatureToDosTextArea.Value = {'Add delete (purge) Course'; 'Fix duplicate import when two different courses have the same student'; 'Add multi-select open key program option  (Note see if it works on the grading tab)'; 'Add a multi-select open student program option  (Note see if it works on the grading tab)'; 'pi cannot be used as an input value'; 'fix renaming functions on the home tab'; ''; ''};
      app.home_keyprog_menu = uicontextmenu(app.UIFigure);
      app.home_open_key = uimenu(app.home_keyprog_menu);
      app.home_open_key.MenuSelectedFcn = createCallbackFcn(app, @home_open_keySelected, true);
      app.home_open_key.Text = 'Open';
      app.home_create_pcode = uimenu(app.home_keyprog_menu);
      app.home_create_pcode.MenuSelectedFcn = createCallbackFcn(app, @home_create_pcodeSelected, true);
      app.home_create_pcode.Text = 'Create P-code';
      app.home_set_to_manual_grade = uimenu(app.home_keyprog_menu);
      app.home_set_to_manual_grade.MenuSelectedFcn = createCallbackFcn(app, @home_set_to_manual_gradeSelected, true);
      app.home_set_to_manual_grade.Text = 'Set to Manual Grade';
      app.home_set_to_auto_grade = uimenu(app.home_keyprog_menu);
      app.home_set_to_auto_grade.MenuSelectedFcn = createCallbackFcn(app, @home_set_to_auto_gradeSelected, true);
      app.home_set_to_auto_grade.Text = 'Set to Auto Grade';
      % Assign app.home_keyprog_menu
      app.home_keyprogs_table.ContextMenu = app.home_keyprog_menu;
      app.tc_programs_table.ContextMenu = app.home_keyprog_menu;
      app.tc_sets_menu = uicontextmenu(app.UIFigure);
      app.tc_add_new_set = uimenu(app.tc_sets_menu);
      app.tc_add_new_set.Text = 'Add New';
      app.tc_remove_set = uimenu(app.tc_sets_menu);
      app.tc_remove_set.MenuSelectedFcn = createCallbackFcn(app, @tc_remove_setSelected, true);
      app.tc_remove_set.Text = 'Remove';
      app.tc_copy_set = uimenu(app.tc_sets_menu);
      app.tc_copy_set.Text = 'Copy';
      % Assign app.tc_sets_menu
      app.tc_cases_table.ContextMenu = app.tc_sets_menu;
      app.grading_student_menu = uicontextmenu(app.UIFigure);
      app.grading_grade_selected_stuprogs = uimenu(app.grading_student_menu);
      app.grading_grade_selected_stuprogs.MenuSelectedFcn = createCallbackFcn(app, @grading_grade_selected_stuprogsSelected, true);
      app.grading_grade_selected_stuprogs.Text = 'Grade Selected Programs';
      app.grading_open_selected_stuprogs = uimenu(app.grading_student_menu);
      app.grading_open_selected_stuprogs.MenuSelectedFcn = createCallbackFcn(app, @grading_open_selected_stuprogsSelected, true);
      app.grading_open_selected_stuprogs.Text = 'Open Selected Programs';
      % Assign app.grading_student_menu
      app.grading_student_table.ContextMenu = app.grading_student_menu;
      app.grading_program_table.ContextMenu = app.grading_student_menu;
      app.home_course_menu = uicontextmenu(app.UIFigure);
      app.home_add_course = uimenu(app.home_course_menu);
      app.home_add_course.MenuSelectedFcn = createCallbackFcn(app, @home_add_courseSelected, true);
      app.home_add_course.Text = 'Add New Course';
      app.home_del_course = uimenu(app.home_course_menu);
      app.home_del_course.Text = 'Delete Selected Course';
      app.home_import_roster = uimenu(app.home_course_menu);
      app.home_import_roster.MenuSelectedFcn = createCallbackFcn(app, @home_import_rosterMenuSelected, true);
      app.home_import_roster.Text = 'Import Roster';
      % Assign app.home_course_menu
      app.home_courses_table.ContextMenu = app.home_course_menu;
      app.home_homework_menu = uicontextmenu(app.UIFigure);
      app.home_add_hw = uimenu(app.home_homework_menu);
      app.home_add_hw.MenuSelectedFcn = createCallbackFcn(app, @home_add_hwMenuSelected, true);
      app.home_add_hw.Text = 'Create a New HW Set';
      app.home_del_hw = uimenu(app.home_homework_menu);
      app.home_del_hw.MenuSelectedFcn = createCallbackFcn(app, @home_del_hwMenuSelected, true);
      app.home_del_hw.Text = 'Delete HW Set';
      app.home_import_keys = uimenu(app.home_homework_menu);
      app.home_import_keys.MenuSelectedFcn = createCallbackFcn(app, @home_import_keysMenuSelected, true);
      app.home_import_keys.Text = 'Import Key Programs';
      % Assign app.home_homework_menu
      app.home_homeworks_table.ContextMenu = app.home_homework_menu;
      app.home_stu_program_menu = uicontextmenu(app.UIFigure);
      app.home_import_submissions = uimenu(app.home_stu_program_menu);
      app.home_import_submissions.MenuSelectedFcn = createCallbackFcn(app, @home_import_submissionsMenuSelected, true);
      app.home_import_submissions.Text = 'Import Student Submissions';
      app.home_del_program = uimenu(app.home_stu_program_menu);
      app.home_del_program.MenuSelectedFcn = createCallbackFcn(app, @home_del_programMenuSelected, true);
      app.home_del_program.Text = 'Delete';
      app.home_rename_program = uimenu(app.home_stu_program_menu);
      app.home_rename_program.MenuSelectedFcn = createCallbackFcn(app, @home_rename_programMenuSelected, true);
      app.home_rename_program.Text = 'Rename';
      app.home_open_stu = uimenu(app.home_stu_program_menu);
      app.home_open_stu.MenuSelectedFcn = createCallbackFcn(app, @home_open_stuSelected, true);
      app.home_open_stu.Text = 'Open';
      % Assign app.home_stu_program_menu
      app.home_stuprogs_table.ContextMenu = app.home_stu_program_menu;
      app.grading_builtin_menu = uicontextmenu(app.UIFigure);
      app.grading_add_builtin_to_std_list = uimenu(app.grading_builtin_menu);
      app.grading_add_builtin_to_std_list.MenuSelectedFcn = createCallbackFcn(app, @grading_add_builtin_to_std_listSelected, true);
      app.grading_add_builtin_to_std_list.Text = 'Add to standard authored list';
      % Assign app.grading_builtin_menu
      app.grading_builtin_detected_table.ContextMenu = app.grading_builtin_menu;
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = MATLAB_Grader

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end